classdef CalciumTraces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TestButton                matlab.ui.control.Button
        TraceTable                matlab.ui.control.Table
        NewnonlinearfitButton     matlab.ui.control.Button
        ShowallButton             matlab.ui.control.Button
        ShowlegendCB              matlab.ui.control.CheckBox
        PlottedROIsList           matlab.ui.control.TextArea
        PlottedROIsTextAreaLabel  matlab.ui.control.Label
        df_Axes                   matlab.ui.control.UIAxes
        TracesAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp     % app    - handle to mainApp
        stack       % array  - imported from mainApp
        stackInfo   % struct - imported from mainApp
        fileName    % string - imported from mainApp
        dataPath    % string - imported from mainApp % Description
        
        interactiveElements % Container for all components that should not be
                            % clicked while something like a new window is going on
        
        activeROIs  % array  - stores the information in app.PlottedROIsList in an easy accesible array
                    %           Is also used to make sure no duplicate or
                    %           invalid entries are in the text area
        ROIsData    % struct with ROI stuff - see mainApp for description
        newCalc     % boolean - Determines if some new calculation has to be saved
    end
    
    methods (Access = private)
        
        function switchInteractivity(app,status)
            for i = 1:length(app.UIFigure.Children)
                % Not all Components (e.g. Axes) have the Enable property
                try
                    app.UIFigure.Children(i).Enable = status;
                catch
                    % Everything is fine
                end
            end     
        end
        
        function updatePlot(app)
            plotTraces(app)
            showFit = 1;
            if showFit == 1
                plotFits(app,'exp')
            end
            plot_dF(app)
            fillTable(app)
        end
        
        function plotTraces(app)
            % The default "Interpreter" would interpret the "_" in the
            % filename as subscript 
            title(app.TracesAxes, sprintf("Mean ROI-fluorescence of %s with %g ROIs", ...
                app.fileName, size(app.ROIsData.traces,2)), 'Interpreter', 'none')

            if isnan(app.activeROIs)
                return
            else
                % cla to not plot on top of the old traces, as we use hold
                cla(app.TracesAxes)
                hold(app.TracesAxes,'on')
                for i = 1:length(app.activeROIs)
                    currROI = app.activeROIs(i);
                    % There are 7 default plotting colors Matlab cycles
                    % through - by setting this colorIndex (=SeriesIndex)
                    % manually the plotted trace has the same color as the
                    % fit - without assigning colors manually
                    plot(app.TracesAxes, app.ROIsData.traces(:,currROI),'SeriesIndex',rem(i,8))
                    text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.traces(end,currROI), ...
                        num2str(currROI))
                end
                xlim(app.TracesAxes, [0,app.stackInfo.nFrames + 10])
                ylim(app.TracesAxes,'padded'); 
                %% If we want to let the y Axis start a 0
                % ylims = ylim(app.TracesAxes);
                % ylim(app.TracesAxes, [0, ylims(2)])
                xlabel(app.TracesAxes,sprintf('Frames, at fps of %.2f Hz', app.stackInfo.fps))
                ylabel(app.TracesAxes,'Fluorescence [a.u.]')
                
                checkLegend(app)
                
                showAverage = 1;
                if showAverage == 1
                    plotAverages(app)
                end

            end
        end
        
        function checkLegend(app)
            if app.ShowlegendCB.Value
                % The plotted fits would get their own legend, so the
                % legend is not allowed to update
                legend(app.TracesAxes, string(app.activeROIs),'AutoUpdate','off');
            else
                % Otherwise an empty/arbitrary legend remains
                legend(app.TracesAxes,'off')
            end
        end
        

        function plotAverages(app)
            %framesMean should go into stackInfo - or so
            framesMean = squeeze(mean(app.stack,[1 2]));
            plot(app.TracesAxes, framesMean, 'k')
            text(app.TracesAxes, app.stackInfo.nFrames+2, framesMean(end), ...
                'av')
            
            if isfield(app.ROIsData, 'averageF_ROIs')
                plot(app.TracesAxes, app.ROIsData.averageF_ROIs, 'k-')
                plot(app.TracesAxes, app.ROIsData.average_fit_y, 'k--')
                text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.averageF_ROIs(end), ...
                    'av(ROIs)')
            end
            
            if isfield(app.ROIsData, 'averageFwoB')
                plot(app.TracesAxes, app.ROIsData.averageFwoB, 'k')
                text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.averageFwoB(end), ...
                    'av-b')
            end
            
            if isfield(app.ROIsData, 'averageFaf')
                plot(app.TracesAxes, app.ROIsData.averageFaf, 'k')
                text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.averageFaf(end), ...
                    'av-b-ROIs')
            end
            
            if isfield(app.ROIsData, 'backgroundValue')
                plot(app.TracesAxes, 1:app.stackInfo.nFrames, ...
                    ones(app.stackInfo.nFrames)*app.ROIsData.backgroundValue, 'k')     
                text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.backgroundValue, ...
                    'b')
            end
        end
            
                
        function plotFits(app,type)
            for i = 1:length(app.activeROIs)
                if and (strcmp(type, 'phys'), isfield(app.ROIsData,'physFit_yFitted'))
                    plot(app.TracesAxes, app.ROIsData.physFit_yFitted(:,app.activeROIs(i)), 'SeriesIndex',rem(i,8))
                elseif and (strcmp(type, 'exp'), isfield(app.ROIsData.expFit,'yFitted'))
                    plot(app.TracesAxes, app.ROIsData.expFit.yFitted(:,app.activeROIs(i)), 'SeriesIndex',rem(i,8))
                else
                    plot(app.TracesAxes, app.ROIsData.linFit(:,app.activeROIs(i)), 'SeriesIndex',rem(i,8))
                end
            end
        end
        
        
         function plot_dF_phys(app)
            title(app.df_Axes, sprintf("df/F0 with %g active ROIs", length(app.activeROIs)), ...
            'Interpreter', 'none')

            cla(app.df_Axes)
            hold(app.df_Axes,'on')
            
            for i = 1:length(app.activeROIs)
                % There are 7 default plotting colors Matlab cycles
                % through - by setting this colorIndex (=SeriesIndex)
                % manually the plotted trace has the same color as the
                % fit - without assigning colors manually
                plot(app.df_Axes, app.ROIsData.physFit_dfF0(:,app.activeROIs(i)),'SeriesIndex',rem(i,8))
                text(app.df_Axes, app.stackInfo.nFrames+2, app.ROIsData.traces(end,app.activeROIs(i)), ...
                    num2str(app.activeROIs(i)))
            end
            
            xlim(app.df_Axes, [0,app.stackInfo.nFrames + 10])
            % The df by F0 trace makes most sense, when centered around 0,
            % which we achieve here by having the same min and max ylim
            ylim(app.df_Axes,'padded'); 
            xlabel(app.df_Axes,sprintf('Frames, at fps of %.2f Hz', app.stackInfo.fps))
            ylabel(app.df_Axes,'Z-score')

            if app.ShowlegendCB.Value
                legend(app.df_Axes, string(app.activeROIs))
            else
                % Otherwise an empty/arbitrary legend remains
                legend(app.df_Axes,'off')                    
            end
                
        end
        
        
        function plot_dF(app)
            title(app.df_Axes, sprintf("df/F0 with %g active ROIs", length(app.activeROIs)), ...
            'Interpreter', 'none')

            cla(app.df_Axes)
            hold(app.df_Axes,'on')
            
            for i = 1:length(app.activeROIs)
                currROI = app.activeROIs(i);
                % There are 7 default plotting colors Matlab cycles
                % through - by setting this colorIndex (=SeriesIndex)
                % manually the plotted trace has the same color as the
                % fit - without assigning colors manually
                plot(app.df_Axes, app.ROIsData.linFit_dfF0(:,currROI),'SeriesIndex',rem(i,8))
                text(app.df_Axes, app.stackInfo.nFrames+2, app.ROIsData.traces(end,currROI), ...
                    num2str(currROI))
            end
            
            xlim(app.df_Axes, [0,app.stackInfo.nFrames + 10])
            % The df by F0 trace makes most sense, when centered around 0,
            % which we achieve here by having the same min and max ylim
            ylim(app.df_Axes,'padded'); 
            ylims = ylim(app.df_Axes);
            newYlim = max(abs(ylims));
            ylim(app.df_Axes, [-newYlim, newYlim])
            xlabel(app.df_Axes,sprintf('Frames, at fps of %.2f Hz', app.stackInfo.fps))
            ylabel(app.df_Axes,'Z-score')

            if app.ShowlegendCB.Value
                legend(app.df_Axes, string(app.activeROIs))
            else
                % Otherwise an empty/arbitrary legend remains
                legend(app.df_Axes,'off')                    
            end
                
        end
        
        
        function readROIsList(app)
            % The text area stores all numbers as cell-strings with ','
            % inbetween the ROI-values, so we need to split them at every ','. 
            % Also as it is a cell and every row is a new cell containing text. (therefore {r}) 
            % str2double gives an array with the numbers -> convenient
            % Preallcation does not work as we can´t read the cell-contents directly
            nRows = length(app.PlottedROIsList.Value);
            app.activeROIs = [];
            for r = 1:nRows
                app.activeROIs = [app.activeROIs, ...
                    str2double(strsplit(app.PlottedROIsList.Value{r},{',',' '}))];
            end
            % After assigning values we discard values that occured more
            % than once (bad user input)
            app.activeROIs = unique(app.activeROIs);
            % Then we get rid of the "0" and of potential NaN aswell
            app.activeROIs = app.activeROIs(app.activeROIs > 0);
            % Now we make sure no number exeeds the number of available ROIsw
            app.activeROIs = app.activeROIs(app.activeROIs <= size(app.ROIsData.traces,2));
            
            % Now the Text Area is updated so the user sees the correct input
            app.PlottedROIsList.Value = num2str(app.activeROIs);
        end
        
        function calculateAverages(app)
            % baseline-free stack, better to work with an extra stack
            % and leave the original stack as it is
            bf_stack = app.stack;
            bf_stack(repmat(app.ROIsData.backgroundMask,[1 1 app.stackInfo.nFrames])) = 0;
            app.ROIsData.averageFwoB = squeeze(sum(bf_stack,[1 2])) / sum(app.ROIsData.backgroundMask==0,'all');
            %toDo
            % average without ROIs (baseline+autoflourescence as a
            % better "baseline"?)
            % average without ROIs and baseline (autoflourescence?)
            %Ever non-zero value in omitStack gets omitted later on
            af_stack = app.stack;
            omitStack = (app.ROIsData.backgroundMask + app.ROIsData.ROIs > 0);
            af_stack(repmat(omitStack,[1 1 app.stackInfo.nFrames])) = 0;
            app.ROIsData.averageFaf = squeeze(sum(af_stack,[1 2])) / sum(omitStack==0,'all');

            app.ROIsData.averageF_ROIs = sum(app.ROIsData.traces,2)/app.ROIsData.nROIs;
        end
        
        
        function linFit(app)
            %column vec of [1 1]; [1 2], [1 3] ...[1 nFrames]
            X = [ones(app.stackInfo.nFrames,1) (1:app.stackInfo.nFrames)'];
            fitVal = X\app.ROIsData.traces;
            app.ROIsData.linFit = X*fitVal;
            app.ROIsData.linFit_dfF0 = ...
                (app.ROIsData.traces-app.ROIsData.linFit)./app.ROIsData.linFit;
            app.newCalc = 1;
        end
        
        
        function physFit(app)
            app.ROIsData.physFit = [];
            pgb = uiprogressdlg(app.UIFigure, ...
                        'Title', 'Calculating physiological fits ');
            
            if isfield(app.ROIsData,'backgroundValue')
                baseline = app.ROIsData.backgroundValue;
            else
                baseline = min(app.stackInfo.mean,[],'all');
            end
            
            x =(1:app.stackInfo.nFrames)';
            % one expDecay for photobleaching
            modelfun = @(b,x) baseline + b(1) * exp(b(2)*x);
            coef0 = [100, -0.005];
            mdl = fitnlm(x, app.ROIsData.averageF_ROIs, modelfun, coef0);
            fitCoeff = mdl.Coefficients{:, 'Estimate'};
            app.ROIsData.average_fit_coeff = fitCoeff;
            app.ROIsData.average_fit_y = baseline + fitCoeff(1) * exp(fitCoeff(2)*x);
            
            photobleaching = fitCoeff(2);
            
            for i = 1:app.ROIsData.nROIs
                pgb.Value = i/app.ROIsData.nROIs;
                app.ROIsData.physFit_yFitted(:,i) = ...
                    baseline + (app.ROIsData.traces(1,i)-baseline) * exp(photobleaching*x);
                app.ROIsData.physFit_dfF0(:,i) = ...
                    (app.ROIsData.traces(:,i) - app.ROIsData.physFit_yFitted(:,i)) ./ ...
                     app.ROIsData.physFit_yFitted(:,i);
            end
            app.newCalc = 1;
            try
                delete(pgb);
            catch
                %apparently the progressbar was already closed by the user?
            end
            
        end
        
        function expFit(app)
            app.ROIsData.expFit = [];
            pgb = uiprogressdlg(app.UIFigure, ...
                        'Title', 'Calculating exponential fits ');
            
            x =(1:app.stackInfo.nFrames)';
            
            % one expDecay for photobleaching then adjusted to the specific
            % trace in it´s values
            % %test test test
            app.ROIsData.averageF_ROIs = sum(app.ROIsData.traces,2)/app.ROIsData.nROIs;
            
            modelfun = @(b,x) b(1) + b(2) * exp(b(3)*x);
            coef0 = [100, 250, -0.005];
            mdl = fitnlm(x, app.ROIsData.averageF_ROIs, modelfun, coef0);
            fitCoeff = mdl.Coefficients{:, 'Estimate'};
            app.ROIsData.average_fit_coeff = fitCoeff;
            app.ROIsData.average_fit_y = fitCoeff(1) + fitCoeff(2) * exp(fitCoeff(3)*x);
            
            %individual = 0;
            %if individual == 1
                for i = 1:app.ROIsData.nROIs
                    pgb.Value = i/app.ROIsData.nROIs;
                    
                    % We need an offset "b(1)" as the curves don´t start and
                    % won´t reach 0 (physiologically)
                    % We need a high "b(2)", because our exponent "b(3)-b(4)*x"
                    % will be very small - Small change in flourescence over
                    % many frames
                    % We need the negative b(3) to adjust the initial speed of decay
                    % We need a very small  b(4) to actually model the process
                    % correctly (many frames, slow decay)
                    modelfun = @(b,x) b(1) + b(2) * exp(b(3)*x);
                    
                    % These values seemd fine when first testing and may need
                    % to be either adjusted later on, or even better
                    % automatically adjusted to the specific curve before
                    % fitting, or entered by the user - so many options
                    coef0 = [100, 250, -0.005];
                
                    mdl = fitnlm(x, app.ROIsData.traces(:,i), modelfun, coef0);
                
                    coef = mdl.Coefficients{:, 'Estimate'};
                    app.ROIsData.expFit.coef(:,i) = coef;
                    app.ROIsData.expFit.yFitted(:,i) = coef(1) + coef(2) * exp(coef(3)*x);
                end
                
           % else
                for i = 1:app.ROIsData.nROIs
                    pgb.Value = i/app.ROIsData.nROIs;
                    
                    modelfun = @(b,x) b(1) + b(2) * exp(app.ROIsData.average_fit_coeff(3)*x);
                    
                    coef0 = [100, 250];
                
                    mdl = fitnlm(x, app.ROIsData.traces(:,i), modelfun, coef0);
                
                    coef = mdl.Coefficients{:, 'Estimate'};
                    app.ROIsData.expFitO.coef(:,i) = coef;
                    app.ROIsData.expFitO.yFitted(:,i) = coef(1) + coef(2) * exp(app.ROIsData.average_fit_coeff(3)*x);
                end
                
              % overallFit-decay, individual reducedFit
                for i = 1:app.ROIsData.nROIs
                    pgb.Value = i/app.ROIsData.nROIs;
                    
                    modelfun = @(b,x) b(1) + b(2) * exp(b(3)-b(4)*x);
                    coef0 = [100, 250, -0.5, -0.005];
                    y = min(app.ROIsData.traces(:,i), app.ROIsData.expFitO.yFitted(:,i));
                    mdl = fitnlm(x, y, modelfun, coef0);
                
                    coef = mdl.Coefficients{:, 'Estimate'};
                    app.ROIsData.expFitR.coef(:,i) = coef;
                    app.ROIsData.expFitR.yFitted(:,i) = coef(1) + coef(2) * exp(coef(3)-coef(4)*x);
                end
            %end
           
            app.newCalc = 1;
            updatePlot(app)
            try
                delete(pgb);
            catch
                %apparently the progressbar was already closed by the user?
            end
        end
        
        
       function updateROIs(app)
            try
                app.mainApp.updateROIs(app.ROIsData)
            catch
                errordlg("Could not load ROIs-Fits and df/F0 data into the main window")
            end
       end
        
        function testplot(app)
            figure('Name','Decay-modelling // -- = individual fit // - = overallFit // : = reducedFit')
            hold on
            colors = ['b','r','y','m','g','c'];
            for n = 1:length(app.activeROIs)
                plot(app.ROIsData.traces(:,app.activeROIs(n)), colors(n))
                plot(app.ROIsData.expFitO.yFitted(:,app.activeROIs(n)),colors(n))
                plot(app.ROIsData.expFit.yFitted(:,app.activeROIs(n)),strcat(colors(n),'--'))
                plot(app.ROIsData.expFitR.yFitted(:,app.activeROIs(n)),strcat(colors(n),':'))
                text(app.stackInfo.nFrames+2, app.ROIsData.traces(end,app.activeROIs(n)), ...
                        num2str(app.activeROIs(n)))
            end
            
            plot(app.ROIsData.averageF_ROIs, 'k')
            plot(app.ROIsData.average_fit_y, 'k--')  
            overallF = squeeze(sum(app.stack,[1,2])/(size(app.stack,1)*size(app.stack,2)));
            plot(overallF,'g')
            
        end
        
        
        function fillTable(app)
            app.TraceTable.ColumnName = {'ROI_num','distance_stim'};
            % as the arrayfun outputs a cell array and the columns should
            % be the same type - num2cell was the easiest solution
            data(:,1) = arrayfun(@(x)sprintf('%-.f',x),app.activeROIs,'UniformOutput',false);
            if isfield(app.ROIsData,'dStim')
                data(:,2) = arrayfun(@(x)sprintf('%.f',x),app.ROIsData.dStim(app.activeROIs),'UniformOutput',false);
            end
            app.TraceTable.Data = data;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            % So no user interaction while loading (relevant when fitting)
            switchInteractivity(app,0)
            app.newCalc = 0;
            
            app.mainApp = mainApp;
            app.stack = mainApp.stack;
            app.stackInfo = mainApp.stackInfo;
            app.fileName = mainApp.fileName;
            app.dataPath = mainApp.dataPath;
            app.ROIsData = mainApp.ROIsData;
            
            disableDefaultInteractivity(app.TracesAxes)
            app.TracesAxes.Toolbar.Visible = false;
            
            app.PlottedROIsList.Value = num2str(1:3);
            readROIsList(app)
            
            change = 0;
            % change will be "1" if there was a change in SegGUI
            if isfield(app.ROIsData, 'change')
                if app.ROIsData.change == 1
                    app.ROIsData.change = 0;
                    change = 1;
                end
            end

            if and(change == 1, isfield(app.ROIsData,'backgroundMask'))
                calculateAverages(app)
            end
            
            % checking for "change" just saves having this if statement twice          
            if or (~isfield(app.ROIsData,'linFit'), ...
                    change == 1)
                linFit(app)
                expFit(app)
            end
            
            updatePlot(app)
            
            switchInteractivity(app,1)
        end

        % Value changed function: PlottedROIsList
        function PlottedROIsListValueChanged(app, event)
            readROIsList(app)
            updatePlot(app)
        end

        % Value changed function: ShowlegendCB
        function ShowlegendCBValueChanged(app, event)
            checkLegend(app)
        end

        % Button pushed function: ShowallButton
        function ShowallButtonPushed(app, event)
            % This functions first fills the table and then reads from it
            % to make sure everything fits together
            app.PlottedROIsList.Value = num2str(1:size(app.ROIsData.traces,2));
            readROIsList(app)
            updatePlot(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if app.newCalc == 1
                    pgbMA = uiprogressdlg(app.mainApp.UIFigure, ...
                        'Title', 'Closing CalciumTraces',...
                        'Indeterminate','on');
                    
                    pgb = uiprogressdlg(app.UIFigure, ...
                        'Title', 'Closing CalciumTraces',...
                        'Indeterminate','on');
                    SaveROIs(app.dataPath, app.fileName, app.ROIsData)
                    updateROIs(app)
                    try
                        delete(pgb)
                        delete(pgbMA)
                    catch
                        error('Progress bar was closed before the files could be updated.')
                    end
                end
            catch e
                disp(e)
            end
            delete(app)
        end

        % Button pushed function: NewnonlinearfitButton
        function NewnonlinearfitButtonPushed(app, event)
            expFit(app)
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            physFit(app)
            plotTraces(app)
            plotFits(app,'phys')
            plot_dF_phys(app)
            %xcorrs
            lag = 5;
            xcorrs = zeros(app.ROIsData.nROIs, app.ROIsData.nROIs, 2*lag+1);
            for corrSource = 1:app.ROIsData.nROIs
                for corrTarget = 1:app.ROIsData.nROIs
                    xcorrs(corrSource,corrTarget,1:(2*lag+1)) = xcorr(app.ROIsData.physFit_dfF0(:,corrSource),app.ROIsData.physFit_dfF0(:,corrTarget),lag);
                end
            end
            figure('Name', 'xcorr -lag')
            imagesc(xcorrs(:,:,1))
            figure('Name', 'xcorr 0lag')
            imagesc(xcorrs(:,:,1+lag))
            figure('Name', 'xcorr +lag')
            imagesc(xcorrs(:,:,1+2*lag))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TracesAxes = uiaxes(app.UIFigure);
            title(app.TracesAxes, 'Title')
            xlabel(app.TracesAxes, 'X')
            ylabel(app.TracesAxes, 'Y')
            zlabel(app.TracesAxes, 'Z')
            app.df_Axes = uiaxes(app.UIFigure);
            title(app.df_Axes, 'Title')
            xlabel(app.df_Axes, 'X')
            ylabel(app.df_Axes, 'Y')
            zlabel(app.df_Axes, 'Z')
            app.PlottedROIsTextAreaLabel = uilabel(app.UIFigure);
            app.PlottedROIsTextAreaLabel.Text = {'Plotted '; 'ROIs'};
            app.PlottedROIsList = uitextarea(app.UIFigure);
            app.PlottedROIsList.ValueChangedFcn = createCallbackFcn(app, @PlottedROIsListValueChanged, true);
            app.ShowlegendCB = uicheckbox(app.UIFigure);
            app.ShowlegendCB.ValueChangedFcn = createCallbackFcn(app, @ShowlegendCBValueChanged, true);
            app.ShowlegendCB.Text = 'Show legend';
            app.ShowallButton = uibutton(app.UIFigure, 'push');
            app.ShowallButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallButtonPushed, true);
            app.ShowallButton.Text = 'Show all';
            app.NewnonlinearfitButton = uibutton(app.UIFigure, 'push');
            app.NewnonlinearfitButton.ButtonPushedFcn = createCallbackFcn(app, @NewnonlinearfitButtonPushed, true);
            app.NewnonlinearfitButton.Text = 'New non-linear fit';
            app.TraceTable = uitable(app.UIFigure);
            app.TraceTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.TraceTable.RowName = {};
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalciumTraces(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end