classdef MainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalciumAnalysisAppUIFigure     matlab.ui.Figure
        LoadpresetfromotherfileButton  matlab.ui.control.Button
        LoadwholefolderButton          matlab.ui.control.Button
        ShowCalciumTracesPB            matlab.ui.control.Button
        ResetSelectedStackPB           matlab.ui.control.Button
        SegmentationGUIButton          matlab.ui.control.Button
        PreviewTable                   matlab.ui.control.Table
        StackGUIButton                 matlab.ui.control.Button
        FilesListBox                   matlab.ui.control.ListBox
        FilesListBoxLabel              matlab.ui.control.Label
        SelectTiffButton               matlab.ui.control.Button
        FoldersListBox                 matlab.ui.control.ListBox
        FoldersListBoxLabel            matlab.ui.control.Label
        PreviewAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        folderPath      % string - folderPath of the selected file 
        fileName        % string - fileName of the selected file (without ext - .tif)
        filePath        % string - fileName including path (with extension)
        dataPath        % string - folderPath  of the .mat files (stack / stackInfo)
        folders         % struct (name, ...) - list of the folders in the parent directory of the selected file
        files           % struct (name, ...) - list of tif(f) files in the current selected folder
        
        stack           % matrix with "uint16" - All the image data
        stackInfo       % struct (format, ..., >30 entries) Metadata
            % with information about the tif file of the first frame
            % Orginally "1 x nFrames" struct, but only
            % Offset and DateTime vary -> Waste of Memory
            % Now nFrames and fps (acquisition frame rate) are included
            % cMin and cMax
            % For other information look into SaveStack
            
        ROIsData        % struct
            % with information about the ROIs
            % .ROIs     - matrix of the size of the stack (2d) with ascending
            %               numbers for the ROIS and "0" for "no ROI"
            % .nROIs    - double -  number of ROIs
            % .sizes    - vector -  number of pixels per ROI
            % .traces   - matrix - Calcium activity per ROI per frame

    end
    
    methods (Access = private)
       
        function switchInteractivity(app,status)
            for i = 1:length(app.CalciumAnalysisAppUIFigure.Children)
                % Not all Components (e.g. Axes) have the Enable property
                try
                    app.CalciumAnalysisAppUIFigure.Children(i).Enable = status;
                catch
                    % Everything is fine
                end
            end
            % These buttons will only be enabled after selecting a folder
            if isempty(app.folderPath)
                app.LoadwholefolderButton.Enable = false;
                app.LoadpresetfromotherfileButton.Enable = false;
                app.ResetSelectedStackPB.Enable = false;
            end
            
            % These buttons will only be enabled after loading data
            if isempty(app.stack)
                app.StackGUIButton.Enable   = false;
                app.SegmentationGUIButton.Enable = false;
            end
            
            if ~isstruct(app.ROIsData)
                app.ShowCalciumTracesPB.Enable = false;
            elseif app.ROIsData.nROIs == 0
                app.ShowCalciumTracesPB.Enable = false;
            end
             
        end
        
        function populateFoldersListBox(app)
            % The ..-"folder" goes one up in the filepath
            supDir = dir(fullfile(app.folderPath,'..'));
            %get rid of "." und ".." that the dir command returns by default
            supDir = supDir(~ismember({supDir.name},{'.','..'})); 
            % the sqaure brackets proce a "logical" array then can be used
            % for indexing. This line then gets rid of every non-folder element
            app.folders = supDir([supDir.isdir]);
            
            % Now we get rid of every folder that does not contain tif
            % files
            nSubFolders = length(app.folders);
            tifFolders = zeros(1,nSubFolders); % default is empty
            for i = 1:nSubFolders
                folDir = dir([app.folders(i).folder, filesep, app.folders(i).name]);
                if any(contains({folDir.name},[".tif",".tiff"]))
                    tifFolders(i) = i; % This folder contains tif
                end
            end
            % This seemed to be the only possibility with a preallocated
            % array
            app.folders = app.folders(tifFolders~=0);
             
            app.FoldersListBox.Items =  {app.folders.name}';
            % Set Value of FoldersListBox, so the corresponding folder is marked
            filesepIdx = strfind(app.folderPath,filesep);
            % +/- 1 to get rid of fileseps ("/")
            folderName = app.folderPath(filesepIdx(end-1)+1:end-1);
            app.FoldersListBox.Value = {folderName};
            populateFilesListBox(app)
        end
        
        
        function populateFilesListBox(app)
            app.files = dir(app.folderPath);
            tifIdx = contains({app.files.name},[".tif",".tiff"]);
            % We only want the tif files names to be displayed
            app.files = app.files(tifIdx);
            app.FilesListBox.Items = {app.files.name}';
        end
        
        
        function plotPreview(app)
            app.PreviewAxes.XLim = [0, app.stackInfo.Width];
            app.PreviewAxes.YLim = [0, app.stackInfo.Height];

            imagesc(app.stack(:,:,1), 'Parent', app.PreviewAxes, ...
                [app.stackInfo.cMin app.stackInfo.cMax]);
            title(app.PreviewAxes,sprintf("Preview of: %s", app.fileName),'Interpreter','none');
            % I´m not sure if the drawnow is necessary, but it felt like
            % the figures sometimes took a blink of an eye to update when
            % the computation (like selecting a new file) was already done
            drawnow
        end
        
      
        function populatePreviewTable(app)
            if isfield(app.ROIsData,'nROIs')
                nROIs = app.ROIsData.nROIs;
            else
                nROIs = 0;
            end
            app.PreviewTable.RowName = {};
            app.PreviewTable.Data = ...
                {'Width [pixel]', sprintf('%g', app.stackInfo.Width); ...
                 'Height [pixel]', sprintf('%g', app.stackInfo.Height); ...
                 'Stack size [frames]', sprintf('%g',app.stackInfo.nFrames); ...
                 'Acquisition fps [Hz]', sprintf('%.3f',app.stackInfo.fps); ...
                 'Substituted frames', sprintf('%g',numel(app.stackInfo.substitutedFrames)); ...
                 'ROIs', sprintf('%g', nROIs)};
        end
        
        
        function readNsave(app)
                [app.stack, app.stackInfo] = ReadTif(app.filePath);
                app.stackInfo = SaveStack(app.dataPath, app.fileName, app.stack, app.stackInfo);        
        end
        
    end
    
    methods (Access = public)
        
        function updateFilesListBox(app, oldFileName, oldFolderPath)
            % If the user did not change the selection in fileListBox the
            % redefinition is not necessary
            % BUT if the user changed to file-selection in the main window
            % inbetween, this is absolutely necessary
            filesepIdx = strfind(oldFolderPath,filesep);
            folderName = oldFolderPath(filesepIdx(end-1)+1:filesepIdx(end)-1);
            app.FoldersListBox.Value = {folderName};
            FoldersListBoxValueChanged(app)
            
            try
                tifName = [oldFileName,'.tif'];
                tiffName = [oldFileName,'.tiff'];
                % it is important to start with "tiff" as .tif is
                % "contained" in .tiff!
                if any(contains({app.files.name},tiffName))
                    app.FilesListBox.Value = tiffName;
                elseif any(contains({app.files.name},tifName))
                    app.FilesListBox.Value = tifName;
                end
            catch e
                disp(e)
            end
            
            % Now that the first part made sure the selected file is
            % actually the one we just worked on - we can "update" the
            % mainApp (e.g. previewtable), but also load the new
            % information. Otherwise the user would need to click another
            % file and then move back to the one that was just changed to
            % update the changes which would be error-prone. (and just bad
            % GUI-coding)
            FilesListBoxValueChanged(app)
            
            
        end
        
        function updateROIs(app,ROIsData)
            % Is called from CalciumTraces as the fit and dF data is
            % calculated there
            app.ROIsData = ROIsData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % check if the necessary toolboxes are installed
            info = ver;
            if ~and(any(strcmp('Image Processing Toolbox', {info.Name})), ...
                    any(strcmp('Statistics and Machine Learning Toolbox', {info.Name})))
                app.delete;
                error('Please install the "Statistics and Machine Learning Toolbox" aswell as the "Image Processing Toolbox"')
            end
            
            % So no user interaction while loading (only relevant when slow)
            switchInteractivity(app,0)
            
            % So the preview Window can´t be dragged around
            disableDefaultInteractivity(app.PreviewAxes)
            
            %load startup params
            % Improvement necessary - This is not implemented yet
            try 
                %Maybe the \ is unnecessary, maybe it can throw errors,
                %behavior not understood yet
                load('defaultParams'); %#ok<LOAD> 
                app.folderPath = folderPath; %#ok<ADPROP> 
                populateFoldersListBox(app)
                
            catch
                %apparently no folder was selected or path was invalid
                disp('No defaultParams file could be found. Either this is the first time you started this app or you forgot to add the folder of the app to your MATLAB path. You can use the pathtool to fix it.')
            end
            
            switchInteractivity(app,1)            
        end

        % Button pushed function: SelectTiffButton
        function SelectTiffButtonPushed(app, event)
            [newFile,newFolder] = uigetfile({'*.tiff; *.tif','tif(f)s (*.tiff,*.tif)'}, ...
                                        'Select a tif(f) file to display the folder');
            %if the uigetfile dialoge was closed without selection nothing
            %should happen and the old file/folder name is preserved
            if newFile == 0
                return
            else
                app.fileName   = newFile;
                app.folderPath = newFolder;
            end
            populateFoldersListBox(app)
            % And "selected" the one that was chosen
            app.FilesListBox.Value = {app.fileName};
            % For proper selection / preview
            FilesListBoxValueChanged(app)

        end

        % Value changed function: FoldersListBox
        function FoldersListBoxValueChanged(app, event)
            % The process should be fast enough to ensure no selection of
            % old files by the user... but you never now, better disable it
            app.FilesListBox.Enable = false;
            selectedFolder = app.FoldersListBox.Value;
            % We have to go one folder back (until filesep in the string)
            filesepIdx = strfind(app.folderPath,filesep);
            supFolderPath = app.folderPath(1:filesepIdx(end-1));
            % To then add the selected folder
            % The extra filesep is to make sure it is not missing at the
            % end, otherwise the supFolderPath will jump one too far up.
            % As an "extra" / is at the end of the folderPath from uigetdir
            app.folderPath = fullfile(supFolderPath,selectedFolder,filesep);
            populateFilesListBox(app)
            % So no file is selected - less confusing and also a preselcted
            % file without any real "choice" (Without clicking) would do no
            % proper loading, while valueChange would not be called by
            % clicking it again
            set(app.FilesListBox, 'Value', {})
            app.FilesListBox.Enable = true;

            
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)
            switchInteractivity(app,0);
            [~,app.fileName,ext] = fileparts(app.FilesListBox.Value);
            flb_pgb = uiprogressdlg(app.CalciumAnalysisAppUIFigure, ...
                'Title', sprintf('Reading tif(f) file: \n%s',app.fileName),...
                'Indeterminate','on');
            app.filePath = [app.folderPath, app.fileName, ext];
            % There is a extra filesep from the
            % FoldersListBoxValueChangedFct so we don´t need it here
            app.dataPath = [app.folderPath, 'matFiles', filesep];
            
            if isfile([app.dataPath, app.fileName, '_stack.mat'])
                [app.stack, app.stackInfo, app.ROIsData] = LoadData(app.dataPath, app.fileName);
            else
                readNsave(app)
            end
            
            plotPreview(app)
            populatePreviewTable(app)
            
            try
                delete(flb_pgb);
            catch
                %apparently the progressbar was already closed by the user?
            end
            
            switchInteractivity(app,1)
        end

        % Button pushed function: StackGUIButton
        function StackGUIButtonPushed(app, event)
            StackGUI(app);
        end

        % Button pushed function: SegmentationGUIButton
        function SegmentationGUIButtonPushed(app, event)
            SegmentationGUI(app);
        end

        % Button pushed function: ResetSelectedStackPB
        function ResetSelectedStackPBPushed(app, event)
            if isfile([app.dataPath, app.fileName, '_stack.mat'])
                delete([app.dataPath, app.fileName, '_stack.mat']);
                delete([app.dataPath, app.fileName, '_stackInfo.mat']);
                if isfile([app.dataPath, app.fileName, '_ROIsData.mat'])
                    delete([app.dataPath, app.fileName, '_ROIsData.mat']);
                end
                app.stack = [];
                app.stackInfo = [];
                app.ROIsData = [];
            end
            FilesListBoxValueChanged(app);
        end

        % Button pushed function: ShowCalciumTracesPB
        function ShowCalciumTracesPBPushed(app, event)
            CalciumTraces(app);
        end

        % Button pushed function: LoadwholefolderButton
        function LoadwholefolderButtonPushed(app, event)
            switchInteractivity(app,0);
            %Proper naming of the Progressbar, cells have the advantage,
            %that every new cell becomes a new row
            nFiles = length(app.FilesListBox.Items);
            progMsg = cell(nFiles,1);
            progress = cell(nFiles,1);
            progress(:) = {'---'};
            for i=1:nFiles
                %Sadly the filenames are differently long and there is no -
                %remotely - easy way known to me to align them :/
                %Excess white space (either by different number of letters,
                %or just as prior blanks - e.g. %15s - fails as the letters
                %are broader than a blank.
                progMsg{i} = sprintf('"%s" \t - \t Progress: %s', app.FilesListBox.Items{i}, progress{i});
            end
            pgb = uiprogressdlg(app.CalciumAnalysisAppUIFigure, ...
                'Title', sprintf('Reading folder: "%s"', app.FoldersListBox.Value),...
                'Message',progMsg, ...
                'Indeterminate','on');
            
            for i=1:nFiles
                [~,app.fileName,ext] = fileparts(app.FilesListBox.Items(i));
                app.filePath = [app.folderPath, app.fileName, ext];            
                % There is a extra filesep from the
                % FoldersListBoxValueChangedFct so we don´t need it here
                app.dataPath = [app.folderPath, 'matFiles', filesep];
                
                progress{i} = 'ongoing';
                progMsg{i} = sprintf('"%s" \t - \t Progress: %s', app.FilesListBox.Items{i}, progress{i});
                pgb.Message = progMsg;
                
                if isfile([app.dataPath, app.fileName, '_stack.mat'])
                    %do nothing
                else
                    readNsave(app)
                end
                
                progress{i} = 'done';
                progMsg{i} = sprintf('"%s" \t - \t Progress: %s', app.FilesListBox.Items{i}, progress{i});
                pgb.Message = progMsg;
                
            end
            msgbox(sprintf('All files in "%s" have been loaded',app.FoldersListBox.Value))
            try
                delete(pgb);
            catch
                %apparently the progressbar was already closed by the user?
            end
            
            switchInteractivity(app,1)
        end

        % Button pushed function: LoadpresetfromotherfileButton
        function LoadpresetfromotherfileButtonPushed(app, event)
            [file,folder] = uigetfile({'*.tiff; *.tif','tif(f)s (*.tiff,*.tif)'}, ...
                                        'Select a tif(f) file to display the folder',app.filePath);
            lpb_pgb = uiprogressdlg(app.CalciumAnalysisAppUIFigure, ...
                'Title', sprintf('Loading stackInfo from: \n%s',file),...
                'Indeterminate','on');
            %if the uigetfile dialoge was closed without selection nothing
            %should happen and the old file/folder name is preserved
            if file == 0
                return
            end
            % So no previos information (cropping) can interfere
            [~,tifName,~] = fileparts(file);
            templateStackInfoPath = fullfile(folder,'matFiles',[tifName,'_stackInfo','.mat']);
            % How to get information about deleted Frames and cropDirection?...
            % May have to change that function first to specifiy which were deleted...
            template = load(templateStackInfoPath);
            
            % Similar experiments may have a smaller window size
            if isfield(template.stackInfo,'cropped')
                cStart = template.stackInfo.cropped(1);
                if template.stackInfo.cropped(2) < size(app.stack,2)
                    cEnd = template.stackInfo.cropped(2);
                else    
                    cEnd = size(app.stack,2);
                end
            else
                cStart = 1;
                cEnd = size(app.stack,2);
            end
            
            if isfield(template.stackInfo,'remainingFrames')
                startFrame = template.stackInfo.remainingFrames(1);
                endFrame = template.stackInfo.remainingFrames(2);
            else
                startFrame = 1;
                endFrame = size(app.stack,3);
            end

            app.stack = app.stack(:,cStart:cEnd,startFrame:endFrame);
            
            sF = template.stackInfo.substitutedFrames;
            if isempty(sF)
                %Saves stack and "recalculates" stackInfo (Width,Height,nF,mean,...)
                app.stackInfo = SaveStack(app.dataPath, app.fileName, app.stack, app.stackInfo);
            else
                % SubstituteBadFrames also saves the stack anyway
                % We just don´t use it by default as the waitbar
                % "substituting bad frames" could be misleading if there
                % were none
                [app.stack, app.stackInfo] = ...
                    SubstituteBadFrames(app.stack, app.stackInfo, sF, app.dataPath, app.fileName);
            end
            
            app.FilesListBoxValueChanged(app)
            
            try
                delete(lpb_pgb);
            catch
                %apparently the progressbar was already closed by the user?
            end
        end

        % Close request function: CalciumAnalysisAppUIFigure
        function CalciumAnalysisAppUIFigureCloseRequest(app, event)
            % Very barebone so far - Improvement needed
            folderPath = app.folderPath; %#ok<ADPROPLC> 
            save('defaultParams.mat','folderPath')
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalciumAnalysisAppUIFigure = uifigure('Visible', 'off');
            app.CalciumAnalysisAppUIFigure.Name = 'CalciumAnalysisApp';
            app.CalciumAnalysisAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @CalciumAnalysisAppUIFigureCloseRequest, true);
            app.PreviewAxes = uiaxes(app.CalciumAnalysisAppUIFigure);
            title(app.PreviewAxes, 'Preview')
            app.PreviewAxes.XLim = [0 1];
            app.PreviewAxes.XTick = [];
            app.PreviewAxes.XTickLabel = '';
            app.PreviewAxes.YTick = [];
            app.PreviewAxes.YTickLabel = '';
            app.PreviewAxes.ZTick = [];
            app.FoldersListBoxLabel = uilabel(app.CalciumAnalysisAppUIFigure);
            app.FoldersListBoxLabel.Text = 'Folders';
            app.FoldersListBox = uilistbox(app.CalciumAnalysisAppUIFigure);
            app.FoldersListBox.Items = {};
            app.FoldersListBox.ValueChangedFcn = createCallbackFcn(app, @FoldersListBoxValueChanged, true);
            app.FoldersListBox.Value = {};
            app.SelectTiffButton = uibutton(app.CalciumAnalysisAppUIFigure, 'push');
            app.SelectTiffButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTiffButtonPushed, true);
            app.SelectTiffButton.Text = 'Select tif(f)';
            app.FilesListBoxLabel = uilabel(app.CalciumAnalysisAppUIFigure);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.CalciumAnalysisAppUIFigure);
            app.FilesListBox.Items = {};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.StackGUIButton = uibutton(app.CalciumAnalysisAppUIFigure, 'push');
            app.StackGUIButton.ButtonPushedFcn = createCallbackFcn(app, @StackGUIButtonPushed, true);
            app.StackGUIButton.Text = 'Stack GUI';
            app.PreviewTable = uitable(app.CalciumAnalysisAppUIFigure);
            app.PreviewTable.ColumnName = '';
            app.PreviewTable.RowName = {''; ''};
            app.SegmentationGUIButton = uibutton(app.CalciumAnalysisAppUIFigure, 'push');
            app.SegmentationGUIButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationGUIButtonPushed, true);
            app.SegmentationGUIButton.Text = 'Segmentation GUI';
            app.ResetSelectedStackPB = uibutton(app.CalciumAnalysisAppUIFigure, 'push');
            app.ResetSelectedStackPB.ButtonPushedFcn = createCallbackFcn(app, @ResetSelectedStackPBPushed, true);
            app.ResetSelectedStackPB.Text = 'Reset selected stack';
            app.ShowCalciumTracesPB = uibutton(app.CalciumAnalysisAppUIFigure, 'push');
            app.ShowCalciumTracesPB.ButtonPushedFcn = createCallbackFcn(app, @ShowCalciumTracesPBPushed, true);
            app.ShowCalciumTracesPB.Text = 'Show Calcium Traces';
            app.LoadwholefolderButton = uibutton(app.CalciumAnalysisAppUIFigure, 'push');
            app.LoadwholefolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadwholefolderButtonPushed, true);
            app.LoadwholefolderButton.Text = 'Load whole folder';
            app.LoadpresetfromotherfileButton = uibutton(app.CalciumAnalysisAppUIFigure, 'push');
            app.LoadpresetfromotherfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpresetfromotherfileButtonPushed, true);
            app.LoadpresetfromotherfileButton.Text = 'Load preset from other file';
            % Show the figure after all components are created
            app.CalciumAnalysisAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalciumAnalysisAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalciumAnalysisAppUIFigure)
        end
    end
end