classdef SegmentationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FrameEF                        matlab.ui.control.NumericEditField
        backgroundEF                   matlab.ui.control.NumericEditField
        BackgroundValueEditFieldLabel  matlab.ui.control.Label
        backgroundPB                   matlab.ui.control.Button
        SmoothingTA                    matlab.ui.control.TextArea
        SmoothingTextAreaLabel         matlab.ui.control.Label
        DeleteStimulusSiteButton       matlab.ui.control.Button
        AddStimulusSiteButton          matlab.ui.control.Button
        DeleteROIsminSizePB            matlab.ui.control.Button
        ROIsMinSize_Label              matlab.ui.control.Label
        ROIs_Label                     matlab.ui.control.Label
        DeleteROILineButton            matlab.ui.control.Button
        DeleteROIFreehandButton        matlab.ui.control.Button
        AddROIFreehandButton           matlab.ui.control.Button
        ROI_ID_BG_CB                   matlab.ui.control.CheckBox
        MinSizeROI_EF                  matlab.ui.control.NumericEditField
        ROIminSizePixelsLabel          matlab.ui.control.Label
        ROI_ID_CB                      matlab.ui.control.CheckBox
        ROI_OpacityEF                  matlab.ui.control.NumericEditField
        ROIOpacityLabel                matlab.ui.control.Label
        ROI_OpacitySlider              matlab.ui.control.Slider
        StackOpacityEF                 matlab.ui.control.NumericEditField
        StackOpacityEditFieldLabel     matlab.ui.control.Label
        StackOpacitySlider             matlab.ui.control.Slider
        cMaxEF                         matlab.ui.control.NumericEditField
        cMaxEFLabel                    matlab.ui.control.Label
        cMinEF                         matlab.ui.control.NumericEditField
        cMinEFLabel                    matlab.ui.control.Label
        StackTypeDropDown              matlab.ui.control.DropDown
        StackTypeDropDownLabel         matlab.ui.control.Label
        SegmentationACusesMinROISizeandsmoothingButton  matlab.ui.control.Button
        StackAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp     % app    - handle to mainApp
        stack       % array  - imported from mainApp
        stackInfo   % struct - imported from mainApp
        fileName    % struct - imported from mainApp
        filePath    % string - imported from mainApp
        dataPath    % string - imported from mainApp
        
        ROIsData    % struct - similar to stackInfo, but for ROIs, is also used
                    %           for saving and loading
            %nROIs      double - number of ROIs
            %ROIs       array - Stores a matrix of the same x-y size as the stack
                            % with labeled (1,2,3,...) ROIs
            %ROI_Sizes  array - stores the number of pixels per ROI
        
        
        cLimit % double - Limit of the value for colorbar, should be between 0 and 65535
        currStack % array (Width x Height) - Stack for plotting (Mean,Median,StD)
        
        changes % If no changes occure there is no reason to save when exiting
        
    end
    
    methods (Access = private)
        
        function switchInteractivity(app,status)
            for i = 1:length(app.UIFigure.Children)
                % Not all Components (e.g. Axes) have the Enable property
                try
                    app.UIFigure.Children(i).Enable = status;
                catch
                    % Everything is fine
                end
            end
            
            reducedInteractivity = 0;
            if ~isfield(app.ROIsData,'ROIs')
                reducedInteractivity = 1;
            elseif app.ROIsData.ROIs == 0
                reducedInteractivity = 1;
            end
                
            if reducedInteractivity == 1
                app.DeleteROIsminSizePB.Enable = false;
                app.DeleteROIFreehandButton.Enable = false;
                app.DeleteROILineButton.Enable = false;
                app.backgroundPB.Enable = false;
                app.backgroundEF.Enable = false;
                app.AddStimulusSiteButton.Enable = false;
                app.DeleteStimulusSiteButton.Enable = false;
            end
        end
        
        
        function plotStack(app)
            % Clims have to be specified last
            imagesc(app.currStack, 'Parent', app.StackAxes, ...
                'AlphaData', app.StackOpacityEF.Value/100, ...
                [app.cMinEF.Value app.cMaxEF.Value]);
            % plot the ROIs
            hold(app.StackAxes,'on')
            if strcmp(app.StackTypeDropDown.Value,'Single frame')
                title(app.StackAxes, sprintf("Frame %g of %s \t ", app.FrameEF.Value, app.fileName),'Interpreter','none');
            else
                title(app.StackAxes, sprintf("%s of %s \t ",app.StackTypeDropDown.Value, app.fileName),'Interpreter','none');
            end
            % The field exists anyway as it is defined in the properties of
            % the app, even no value was ever assigned
            if isfield(app.ROIsData,'ROIs')
                plotROIs(app)
            end
            
            if isfield(app.ROIsData,'stimPos')
                pos = app.ROIsData.stimPos;
                plot(app.StackAxes,pos(1),pos(2),'*','MarkerSize',12, ...
                    'MarkerEdgeColor','white', 'LineWidth', 2)
            end
            hold(app.StackAxes,'off')
        end
        
        
        function plotROIs(app)
            ROI_bounds = bwboundaries(app.ROIsData.ROIs);
            try
                for i=1:numel(ROI_bounds)
                    % ROIs below minSize will be plotted differently to show
                    % which ones would be considered too small before removing
                    % them
                    if app.ROIsData.sizes(i) >= app.MinSizeROI_EF.Value
                    plot(ROI_bounds{i}(:,2), ROI_bounds{i}(:,1), ...
                        'Parent', app.StackAxes, ...
                        'Color', [1 0 0 app.ROI_OpacityEF.Value/100]);                    
                    else
                    plot(ROI_bounds{i}(:,2), ROI_bounds{i}(:,1), ...
                        'Parent', app.StackAxes, ...
                        'Color', [0 0 0 app.ROI_OpacityEF.Value/100]);                     
                    end
                end
            catch
                errordlg(['ROI saving did not work properly and' ...
                    'therefore number of ROIs is ill-defined.' ...
                    'Please don´t jump around windows, interrupt ROI drawing' ...
                    '  or open the Segmentation GUI more than once!'],'Error','ok')
            end
            
            if app.ROI_ID_CB.Value == true
                if app.ROI_ID_BG_CB.Value == 1
                    bgC = [1 1 1];
                else
                    bgC = 'none';
                end
                for i=1:app.ROIsData.nROIs
                    text(ROI_bounds{i}(1,2),ROI_bounds{i}(1,1),num2str(i), ...
                        'Color','k', 'FontSize',12 , 'FontWeight','bold',...
                        'BackgroundColor',bgC, 'Parent',app.StackAxes);
                end
            end
        end
        
        
        function selectStack(app,stackType)
            cMin = app.stackInfo.cMin;
            cMax = app.stackInfo.cMax;
            
            if strcmp(stackType,'Single frame')
                app.FrameEF.Visible = true;
                app.FrameEF.Editable = true;
                app.FrameEF.Limits = [1, app.stackInfo.nFrames];
                app.currStack = app.stack(:,:,app.FrameEF.Value);
            else
                app.FrameEF.Visible = false;
                app.FrameEF.Editable = false;
                if strcmp(stackType, 'Mean')
                    app.currStack = app.stackInfo.mean;
                elseif strcmp(stackType,'Median')
                    app.currStack = app.stackInfo.median;   
                elseif strcmp(stackType,'StD')
                    app.currStack = app.stackInfo.std;
                    % Needs to be a rounded double for the EF, did not find a
                    % neater solution
                    cMin = double(round(quantile(app.currStack, 0.15, 'all')));
                    cMax = double(round(quantile(app.currStack, 0.95, 'all')));
                end
            end
            
            app.cMinEF.Limits = app.cLimit;
            app.cMaxEF.Limits = app.cLimit;
            app.cMinEF.Value = cMin;
            app.cMaxEF.Value = cMax;
        end
        
        
        function cLimitChange(app)
            % There needs to be a "range"
            if abs(app.cMinEF.Value - app.cMaxEF.Value) == 0
                % If lower value is zero - it can´t be decreased
                if app.cMinEF.Value == app.cLimit(1)
                    app.cMaxEF.Value = app.cMinEF.Value +1;
                else
                    app.cMinEF.Value = app.cMaxEF.Value -1;
                end
            end
            
            app.cMinEF.Limits = [app.cLimit(1), app.cMaxEF.Value];
            app.cMaxEF.Limits = [app.cMinEF.Value, app.cLimit(2)];
        end
            
        
        function segmentationAC(app)
            % Identification of ROIs by using the inbuild active contour
            % function, the basis will be the current stack
            % (mean/median/std)
            
            progBar = waitbar(0,'Please wait for "active contour segmentation" to finish');
            
            cAdjustedStack = app.currStack;
            cAdjustedStack(cAdjustedStack < app.cMinEF.Value) = 0;
            %cAdjustedStack(cAdjustedStack > app.cMaxEF.Value) = app.cLimit(2);
            
            % Defines the starting points of the AC evolution
            % only the bright spots are a good start
            %AC_start = cAdjustedStack > prctile(cAdjustedStack,95);
            AC_start = cAdjustedStack > (app.cMinEF.Value + app.cMaxEF.Value)/2;
            waitbar(0.1,progBar);
            
            BW = activecontour(cAdjustedStack, AC_start,35,'Edge','ContractionBias',0.4);
            waitbar(0.6, progBar);
            % AC only produces a Black-White (0-1) image
            % bwlabel assigns numbers from 1 - "last ROI"
            % Also imfill works MUCH better after ROIs get differentiated
            % by bwlabel!
            app.ROIsData.ROIs = bwlabel(BW);
            
            waitbar(0.7, progBar);
            %deleting the smallROIs first helps speed up
            %deletePixelWithoutNeighbor
            deleteSmallROIs(app)
            
            %smoooooth
            smooths = [];
            nRows = length(app.SmoothingTA.Value);
            for r = 1:nRows
                smooths = [smooths, ...
                    str2double(strsplit(app.SmoothingTA.Value{r},{',',' '}))]; %#ok<AGROW> 
            end
            
            for x = 1:length(smooths)
                deletePixelWithoutNeighbors(app,smooths(x))
            end
            
            waitbar(0.8, progBar);
            relabelROIs(app)
            %After relabling those ROIs that were affected by
            %deletePixelWithoutNeighbor are considered 2 seperate ROIs, so
            %if there are some stray pixel left, they will then be deleted
            %as too small again.
            deleteSmallROIs(app)
            
            waitbar(0.9, progBar);
            plotStack(app)
            
            delete(progBar)
        end
        
        
        function deleteSmallROIs(app)
            % imfill fills regions where "0" is inbetween ROIs, e.g. small
            % black dots/region - it´s most likely still the same cell.
            % Not adressing those "0" causes trouble with bwboundaries in
            % plotROI.
            ROIs = imfill(app.ROIsData.ROIs,'holes');
            rp = regionprops(ROIs,'Area');
            A = [rp.Area];
            
            for i=1:length(A)
                if(A(i)<app.MinSizeROI_EF.Value)
                    ROIs(ROIs==i) = 0;
                end
            end
            
            %Sometimes very small ROIs are inbetween bigger ROIs - they
            %won´t be filled, but disapper in the loop, so we fill those
            %holes again
            app.ROIsData.ROIs = imfill(ROIs,'holes');
            
            relabelROIs(app)
        end
        
        function deletePixelWithoutNeighbors(app,pixCount)
            s = size(app.ROIsData.ROIs);
            M = zeros(s);
            for r = 2:s(1)-1
                for c = 2:s(2)-1
                    if app.ROIsData.ROIs(r,c)>0
                        M(r,c) = sum(app.ROIsData.ROIs(r+[-1;0;1], c+[-1;0;1])>0,'all');
                    end
                end
            end
            app.ROIsData.ROIs(M<=pixCount) = 0;
        end
        
        
        function relabelROIs(app)
            % From delete and add callbacks as they all need relabeling
            % Labeling all ROIs new may seem inefficient, but it is the
            % safest way to make sure the numbering stays correct - Which
            % is also important for the corresponding ROIsData.sizes property!
            app.ROIsData.ROIs = bwlabel(app.ROIsData.ROIs);
            % Similar reason for regionprops - We can´t know if the new ROI
            % will get "the last number", if not the table is out of sync.
            rp = regionprops(app.ROIsData.ROIs,'Area');
            app.ROIsData.sizes = [rp.Area];
            app.ROIsData.nROIs = length(app.ROIsData.sizes);
            
            app.changes = 1;
            if isfield(app.ROIsData,'ROIs')
                ROI_labels_text(app)
            end
        end
        
        
        function ROI_labels_text(app)
            app.ROIs_Label.Text = sprintf('ROIs: %.f', app.ROIsData.nROIs);
            app.ROIsMinSize_Label.Text = ...
                sprintf('ROIs < Min ROI Size: %.f', length(find(app.ROIsData.sizes < app.MinSizeROI_EF.Value)));
            app.DeleteROIsminSizePB.Text = sprintf('Delete ROIs < %.f Pixels', app.MinSizeROI_EF.Value);
        end
        
        
        function calculateCalciumTraces(app)
            app.ROIsData.traces = zeros(app.stackInfo.nFrames, app.ROIsData.nROIs);
            for t = 1:app.stackInfo.nFrames
                tStack = app.stack(:,:,t);
                for i = 1:app.ROIsData.nROIs
                    app.ROIsData.traces(t,i) = mean(tStack(app.ROIsData.ROIs == i));
                end
            end
        end
        
        function calculateROIs(app)
            % The fits produce warnings as some to most of the traces do
            % not have a good fittable slope...
            warning('off','all')
            calculateAverages(app)
            physFit(app)
            expFit(app)
            linFit(app)
            baseline_dfF0(app)
            warning('on','all')
        end
            
        function calculateAverages(app)
            % averageF and Background (computed in another function) would
            % also fit into stackInfo, maybe that´s an update for the
            % future
            % average Flourescence of the whole stack
            app.ROIsData.averageF = squeeze(mean(app.stack,[1 2]));
            % average Flourescence without background and without ROIs ->
            % autoflourescence (af)
            af_stack = app.stack;
            omitStack = (app.ROIsData.backgroundMask + app.ROIsData.ROIs > 0);
            af_stack(repmat(omitStack,[1 1 app.stackInfo.nFrames])) = 0;
            app.ROIsData.averageF_af = squeeze(sum(af_stack,[1 2])) / sum(omitStack==0,'all');
            
            % average Flourescence of all ROIs
            app.ROIsData.averageF_ROIs = sum(app.ROIsData.traces,2)/app.ROIsData.nROIs;
        end
        

        %This try to have a physiological fit will try to access the
        %"dynamic range" (e.g. what happens on top of the baseline) by
        %checking if the there is value of the trace that is lower than the
        %respective fit - as the fit should show the UNDERLYING Calcium
        %dynamic this shouldn´t happen - If it happens a new dynamic range
        %will be chosen (not in a loop, so there may be some "unfitting"
        %values left, but doing it again and again until it works feels too
        %much like number play. Also the physiological fit takes the
        %calcium trace decay (photobleaching) of the average trace as
        %coefficient
        function physFit(app)
            app.ROIsData.physFit = [];
            pgb = uiprogressdlg(app.UIFigure, ...
                        'Title', 'Calculating physiological fits ');
            
            if isfield(app.ROIsData,'backgroundValue')
                baseline = app.ROIsData.backgroundValue;
            else
                baseline = min(app.stackInfo.mean,[],'all');
            end
            
            x =(1:app.stackInfo.nFrames)';
            % one expDecay for photobleaching
            modelfun = @(b,x) baseline + b(1) * exp(b(2)*x);
            coef0 = [100, -0.005];
            mdl = fitnlm(x, app.ROIsData.averageF_ROIs, modelfun, coef0);
            fitCoeff = mdl.Coefficients{:, 'Estimate'};
            app.ROIsData.averageFit_coeff = fitCoeff;
            app.ROIsData.averageFit_yFitted = baseline + fitCoeff(1) * exp(fitCoeff(2)*x);
            
            photobleaching = fitCoeff(2);
            [minValue, minIndex] = min(app.ROIsData.traces(:,:));
            
            for i = 1:app.ROIsData.nROIs
                pgb.Value = i/app.ROIsData.nROIs;
                dynamicRange = (minValue(i)-baseline)*exp(-photobleaching*minIndex(i));
                app.ROIsData.physFit_yFitted(:,i) = ...
                    baseline + dynamicRange * exp(photobleaching*x);
                % THis checks if the fit is really displaying the
                % underlying calcium dynamic
                if any(find(app.ROIsData.traces(:,i) < app.ROIsData.physFit_yFitted(:,i)))
                    [~,ind] = min(app.ROIsData.traces(:,i) - app.ROIsData.physFit_yFitted(:,i));
                    dynamicRange = (app.ROIsData.traces(ind,i)-baseline)*exp(-photobleaching*ind);
                    app.ROIsData.physFit_yFitted(:,i) = ...
                        baseline + dynamicRange * exp(photobleaching*x);
                end
                app.ROIsData.physFit_dfF0(:,i) = ...
                    (app.ROIsData.traces(:,i) - app.ROIsData.physFit_yFitted(:,i)) ./ ...
                     app.ROIsData.physFit_yFitted(:,i);
            end
            
            try
                delete(pgb);
            catch
                errordlg("Please don´t close the progressbar while the program is running. If you did not close the progressbar, this error was caused by some unforseen isssue in the 'physfit'")
                %apparently the progressbar was already closed by the user?
            end
            
        end
        
        
        function expFit(app)
            pgb = uiprogressdlg(app.UIFigure, ...
                        'Title', 'Calculating exponential fits ');
            
            x =(1:app.stackInfo.nFrames)';
            
            % one expDecay for photobleaching then adjusted to the specific
            % trace in it´s values
            for i = 1:app.ROIsData.nROIs
                pgb.Value = i/app.ROIsData.nROIs;
                % We need an offset "b(1)" as the curves don´t start and
                % won´t reach 0 (physiologically)
                % "b(2)" is the "dynamic range", e.g. what happens between baseline (b(1))
                % and the highest possible point
                % The exponent "b(3)" will be very small - 
                % Small change in flourescence overmany frames
                modelfun = @(b,x) b(1) + b(2) * exp(b(3)*x);
                
                % These values seemd fine when first testing and may need
                % to be either adjusted later on, or even better
                % automatically adjusted to the specific curve before
                % fitting, or entered by the user - so many options
                coef0 = [100, 250, -0.005];
            
                mdl = fitnlm(x, app.ROIsData.traces(:,i), modelfun, coef0);
            
                coef = mdl.Coefficients{:, 'Estimate'};
                app.ROIsData.expFit_coef(:,i) = coef;
                app.ROIsData.expFit_yFitted(:,i) = coef(1) + coef(2) * exp(coef(3)*x);
                app.ROIsData.expFit_dfF0(:,i) = ...
                    (app.ROIsData.traces(:,i) - app.ROIsData.expFit_yFitted(:,i)) ./ ...
                     app.ROIsData.expFit_yFitted(:,i);
            end
            
            try
                delete(pgb);
            catch
                %apparently the progressbar was already closed by the user?
            end
        end
        
                
        function linFit(app)
            %column vec of [1 1]; [1 2], [1 3] ...[1 nFrames]
            X = [ones(app.stackInfo.nFrames,1) (1:app.stackInfo.nFrames)'];
            fitVal = X\app.ROIsData.traces;
            app.ROIsData.linFit_yFitted = X*fitVal;
            app.ROIsData.linFit_dfF0 = ...
                (app.ROIsData.traces-app.ROIsData.linFit_yFitted)./app.ROIsData.linFit_yFitted;
        end
        
        function baseline_dfF0(app)
            app.ROIsData.baseline_dfF0 = ...
                (app.ROIsData.traces - app.ROIsData.backgroundValue) ./ ...
                app.ROIsData.backgroundValue;
            
        end

    
       function updateMainApp(app)
            try
                app.mainApp.updateFilesListBox(app.fileName,app.filePath)
            catch
                errordlg("Could not reload the FilesListBox, click a file in the main window to make changes appear")
            end
       end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            % So no user interaction while loading (only relevant when slow)
            switchInteractivity(app,0)
            
            app.mainApp = mainApp;
            app.stack = mainApp.stack;
            app.stackInfo = mainApp.stackInfo;
            app.fileName = mainApp.fileName;
            app.filePath = mainApp.filePath;
            app.dataPath = mainApp.dataPath;
            app.ROIsData = mainApp.ROIsData;
            app.cLimit = [0, 65535];
            
            disableDefaultInteractivity(app.StackAxes)
            app.StackAxes.Toolbar.Visible = false;
            % By setting XLim and YLim to the size of the axes, it is
            % ensured that 1 pixel of the image correspods to 1 pixel
            % of the axes --> No distortion
            % Though this will lead to problems if the image is bigger than
            % the limits....
            if or (app.StackAxes.Position(3) > app.stackInfo.Width, ...
                   app.StackAxes.Position(4) < app.stackInfo.Height)
                app.StackAxes.XLim = [0, app.StackAxes.Position(3)];
                app.StackAxes.YLim = [0, app.StackAxes.Position(4)];
            else
                errordlg('The image is bigger than the AxesWindow and is therefore distorted')
            end
            
            
            app.ROI_ID_BG_CB.Enable = 0; % will only be enabled if ROI_IDs are actually displayed
            
            selectStack(app,app.StackTypeDropDown.Value)
            
            % To properly define the cLimits of the EFs
            cLimitChange(app)
            plotStack(app)
            
            if isfield(app.ROIsData,'sizes')
                ROI_labels_text(app)
            end
            
            if isfield(app.ROIsData,'backgroundValue')
                app.backgroundEF.Value = app.ROIsData.backgroundValue;
            else
                app.backgroundEF.Value = app.cMinEF.Value;
                % This means a background was not manually defined before,
                % Which we could also generate before closing but this seems cleaner
                app.ROIsData.backgroundValue = app.cMinEF.Value;
                app.ROIsData.backgroundMask = app.stackInfo.mean < app.ROIsData.backgroundValue;
            end
            
            app.changes = 0;
            switchInteractivity(app,1)
        end

        % Button pushed function: 
        % SegmentationACusesMinROISizeandsmoothingButton
        function SegmentationACusesMinROISizeandsmoothingButtonPushed(app, event)
            switchInteractivity(app,0)
            segmentationAC(app)
            switchInteractivity(app,1)
        end

        % Value changed function: StackTypeDropDown
        function StackTypeDropDownValueChanged(app, event)
            selectStack(app,app.StackTypeDropDown.Value)
            plotStack(app)
        end

        % Value changed function: FrameEF
        function FrameEFValueChanged(app, event)
            selectStack(app,app.StackTypeDropDown.Value)
            plotStack(app)
        end

        % Value changed function: cMinEF
        function cMinEFValueChanged(app, event)
            cLimitChange(app)
            plotStack(app);
        end

        % Value changed function: cMaxEF
        function cMaxEFValueChanged(app, event)
            cLimitChange(app)
            plotStack(app);
        end

        % Value changed function: StackOpacityEF
        function StackOpacityEFValueChanged(app, event)
            app.StackOpacitySlider.Value = app.StackOpacityEF.Value;
            plotStack(app)
        end

        % Value changed function: StackOpacitySlider
        function StackOpacitySliderValueChanged(app, event)
            app.StackOpacitySlider.Value = round(app.StackOpacitySlider.Value);
            app.StackOpacityEF.Value = app.StackOpacitySlider.Value;
            plotStack(app)
        end

        % Value changed function: ROI_OpacityEF
        function ROI_OpacityEFValueChanged(app, event)
            app.ROI_OpacitySlider.Value = app.ROI_OpacityEF.Value;
            plotStack(app)
        end

        % Value changed function: ROI_OpacitySlider
        function ROI_OpacitySliderValueChanged(app, event)
            app.ROI_OpacitySlider.Value = round(app.ROI_OpacitySlider.Value);
            app.ROI_OpacityEF.Value = app.ROI_OpacitySlider.Value;
            plotStack(app)
        end

        % Value changed function: ROI_ID_CB
        function ROI_ID_CBValueChanged(app, event)
            plotStack(app)
            if app.ROI_ID_CB.Value == 1
                app.ROI_ID_BG_CB.Enable = 1;
                app.ROI_ID_BG_CB.Visible = 1;
            else
                app.ROI_ID_BG_CB.Visible = 0;
                app.ROI_ID_BG_CB.Enable = 0;
            end
        end

        % Value changed function: ROI_ID_BG_CB
        function ROI_ID_BG_CBValueChanged(app, event)
            plotStack(app)
        end

        % Value changed function: MinSizeROI_EF
        function MinSizeROI_EFValueChanged(app, event)
            ROI_labels_text(app)
            plotStack(app)
        end

        % Button pushed function: DeleteROIsminSizePB
        function DeleteROIsminSizePBPushed(app, event)
            switchInteractivity(app,0)
            deleteSmallROIs(app)
            plotStack(app)
            switchInteractivity(app,1)
        end

        % Button pushed function: AddROIFreehandButton
        function AddROIFreehandButtonPushed(app, event)
            switchInteractivity(app,0)
            newROI = drawfreehand(app.StackAxes, 'DrawingArea', ... 
                    [0,0,app.stackInfo.Width,app.stackInfo.Height]);
            % Creates a "mask" that contains the "pixels" of the new ROI
            newPix = newROI.createMask;
            delete(newROI)
            newPix = imfill(newPix,'holes');
                        
            % Otherwise adding ROIs manually only works after using
            % activeContour, because the field must be created first
            if ~isfield(app.ROIsData,'ROIs')
                app.ROIsData.ROIs = bwlabel(newPix);
            else
                app.ROIsData.ROIs = bwlabel(app.ROIsData.ROIs + newPix);
            end
            relabelROIs(app)
            plotStack(app)
            switchInteractivity(app,1)
        end

        % Button pushed function: DeleteROIFreehandButton
        function DeleteROIFreehandButtonPushed(app, event)
            switchInteractivity(app,0)
            delROI = drawfreehand(app.StackAxes, 'DrawingArea', ... 
            [0,0,app.stackInfo.Width,app.stackInfo.Height], 'color','r');
            delPix = delROI.createMask;
            delete(delROI)
            app.ROIsData.ROIs(delPix == 1) = 0;
            %Defining connectivity of 4 - only edges - reduces the fill-up
            app.ROIsData.ROIs = imfill(app.ROIsData.ROIs, 4, 'holes');
            
            relabelROIs(app)
            plotStack(app)
            switchInteractivity(app,1)
        end

        % Button pushed function: DeleteROILineButton
        function DeleteROILineButtonPushed(app, event)
            switchInteractivity(app,0)
            delROI = drawline(app.StackAxes, 'DrawingArea', ... 
            [0, 0, app.stackInfo.Width, app.stackInfo.Height],...
            'color','r','LineWidth',2,'MarkerSize',4);
            mask = delROI.createMask;
            delete(delROI)
            idx = find(mask ==1);
            % The line is too narrow individually, though I did not find a
            % possibility to increase the line size in the mask with a
            % smart command. LineWidth and MarkerSize only affect the
            % visualisation! :/
            % idx +/-1 broadens the line in vertical direction,
            % idx +/- rownumber broadens the line horizontal direction (jumps
            % to the next column)
            delPix = unique([idx; idx+1; idx-1; idx+size(mask,1); idx-size(mask,1)]);
            app.ROIsData.ROIs(delPix) = 0;
            
            % The first Relabel makes sure the new most likely smaller ROIs
            % are considered an individual ROI again, so they can be
            % deleted by deleteSmallROIs
            relabelROIs(app)
            
            plotStack(app)
            switchInteractivity(app,1)
        end

        % Button pushed function: backgroundPB
        function backgroundPBPushed(app, event)
            switchInteractivity(app,0)
            background = drawfreehand(app.StackAxes, 'DrawingArea', ... 
                    [0,0,app.stackInfo.Width,app.stackInfo.Height]);
            % Creates a "mask" that contains the "pixels" of the new ROI
            % Filling holes prevents having a cell array with 2 2 arrays
            % which would generate "funny" results.
            userDefinedBackground = imfill(background.createMask,'holes');
            delete(background)
            %If the selection of a background fails, the value will be NaN
            %in the end, so it´s better stop this completely
            if sum(userDefinedBackground,'all') == 0
                return
            end
            app.ROIsData.backgroundValue = round(quantile(app.stackInfo.mean(userDefinedBackground),0.98));
            app.ROIsData.backgroundMask = app.stackInfo.mean < app.ROIsData.backgroundValue;
            
            app.backgroundEF.Value = app.ROIsData.backgroundValue;
            app.changes = 1;
            plotStack(app)
            switchInteractivity(app,1)
        end

        % Button pushed function: AddStimulusSiteButton
        function AddStimulusSiteButtonPushed(app, event)
            switchInteractivity(app,0)
            stimSite = drawpoint(app.StackAxes, 'DrawingArea', ... 
                    [0,0,app.stackInfo.Width,app.stackInfo.Height]);
            app.ROIsData.stimPos = round(stimSite.Position);
            delete(stimSite)
            ROI_centers = regionprops(app.ROIsData.ROIs,'Centroid');
            ROI_centers = reshape([ROI_centers.Centroid],2,length(ROI_centers));
            ROI_centers = [ROI_centers(1,:);ROI_centers(2,:)]';
            app.ROIsData.dStim = sqrt((ROI_centers(:,1) - app.ROIsData.stimPos(1)).^2 + ...
                (ROI_centers(:,2) - app.ROIsData.stimPos(2)).^2);
            
            plotStack(app)
            
%             %Check position of Centroids
%             for x =1:length(ROI_centers)
%                 plot(app.StackAxes,ROI_centers(x,1),ROI_centers(x,2),'w*')
%             end
            app.changes = 1;
            switchInteractivity(app,1)
        end

        % Button pushed function: DeleteStimulusSiteButton
        function DeleteStimulusSiteButtonPushed(app, event)
            switchInteractivity(app,0)
            
            if isfield(app.ROIsData,'stimPos')
                app.ROIsData = rmfield(app.ROIsData,{'stimPos','dStim'});
                plotStack(app)
                app.changes = 1;
            end
            
            switchInteractivity(app,1)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if app.changes == 1
                    pgbMA = uiprogressdlg(app.mainApp.CalciumAnalysisAppUIFigure, ...
                        'Title', 'Closing SegmentationGUI',...
                        'Indeterminate','on');
                    pgb = uiprogressdlg(app.UIFigure, ...
                        'Title', 'Closing SegmentationGUI',...
                        'Indeterminate','on');
                    
                    if ~app.ROIsData.nROIs == 0
                        calculateCalciumTraces(app)
                        calculateROIs(app)
                    end
                    % If app.ROIsData.nROIs == 0 saving will actually delete
                    % the file - just to make sure
                    SaveROIs(app.dataPath, app.fileName, app.ROIsData)
                    
                    updateMainApp(app)
                    try
                        delete(pgb)
                        delete(pgbMA)
                    catch e
                        disp(e)
                        error('Progress bar was closed before the files could be updated. Segmentation data may be uncomplete')
                    end
                end
                
            catch e 
                disp(e)
                delete(app)
                error('Something went wrong when trying to close Segmentation GUI')
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StackAxes = uiaxes(app.UIFigure);
            title(app.StackAxes, {'fileName'; ''})
            app.StackAxes.XTick = [];
            app.StackAxes.YTick = [];
            app.SegmentationACusesMinROISizeandsmoothingButton = uibutton(app.UIFigure, 'push');
            app.SegmentationACusesMinROISizeandsmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationACusesMinROISizeandsmoothingButtonPushed, true);
            app.SegmentationACusesMinROISizeandsmoothingButton.Text = {'Segmentation - AC'; '(uses Min ROI Size and smoothing)'};
            app.StackTypeDropDownLabel = uilabel(app.UIFigure);
            app.StackTypeDropDownLabel.Text = 'Stack Type';
            app.StackTypeDropDown = uidropdown(app.UIFigure);
            app.StackTypeDropDown.Items = {'Mean', 'Median', 'StD', 'Single frame'};
            app.StackTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StackTypeDropDownValueChanged, true);
            app.StackTypeDropDown.Value = 'Mean';
            app.cMinEFLabel = uilabel(app.UIFigure);
            app.cMinEFLabel.Text = 'cMin';
            app.cMinEF = uieditfield(app.UIFigure, 'numeric');
            app.cMinEF.ValueChangedFcn = createCallbackFcn(app, @cMinEFValueChanged, true);
            app.cMaxEFLabel = uilabel(app.UIFigure);
            app.cMaxEFLabel.Text = 'cMax';
            app.cMaxEF = uieditfield(app.UIFigure, 'numeric');
            app.cMaxEF.ValueChangedFcn = createCallbackFcn(app, @cMaxEFValueChanged, true);
            app.StackOpacitySlider = uislider(app.UIFigure);
            app.StackOpacitySlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.StackOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @StackOpacitySliderValueChanged, true);
            app.StackOpacitySlider.MinorTicks = [];
            app.StackOpacitySlider.Value = 100;
            app.StackOpacityEditFieldLabel = uilabel(app.UIFigure);
            app.StackOpacityEditFieldLabel.Text = 'Stack Opacity';
            app.StackOpacityEF = uieditfield(app.UIFigure, 'numeric');
            app.StackOpacityEF.Limits = [0 100];
            app.StackOpacityEF.ValueChangedFcn = createCallbackFcn(app, @StackOpacityEFValueChanged, true);
            app.StackOpacityEF.Value = 100;
            app.ROI_OpacitySlider = uislider(app.UIFigure);
            app.ROI_OpacitySlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ROI_OpacitySlider.ValueChangedFcn = createCallbackFcn(app, @ROI_OpacitySliderValueChanged, true);
            app.ROI_OpacitySlider.MinorTicks = [];
            app.ROI_OpacitySlider.Value = 100;
            app.ROIOpacityLabel = uilabel(app.UIFigure);
            app.ROIOpacityLabel.Text = 'ROI Opacity';
            app.ROI_OpacityEF = uieditfield(app.UIFigure, 'numeric');
            app.ROI_OpacityEF.Limits = [0 100];
            app.ROI_OpacityEF.ValueChangedFcn = createCallbackFcn(app, @ROI_OpacityEFValueChanged, true);
            app.ROI_OpacityEF.Value = 100;
            app.ROI_ID_CB = uicheckbox(app.UIFigure);
            app.ROI_ID_CB.ValueChangedFcn = createCallbackFcn(app, @ROI_ID_CBValueChanged, true);
            app.ROI_ID_CB.Text = 'Show ROI ID';
            app.ROIminSizePixelsLabel = uilabel(app.UIFigure);
            app.ROIminSizePixelsLabel.Text = 'ROI minSize (Pixels)';
            app.MinSizeROI_EF = uieditfield(app.UIFigure, 'numeric');
            app.MinSizeROI_EF.Limits = [0 Inf];
            app.MinSizeROI_EF.ValueChangedFcn = createCallbackFcn(app, @MinSizeROI_EFValueChanged, true);
            app.MinSizeROI_EF.Value = 25;
            app.ROI_ID_BG_CB = uicheckbox(app.UIFigure);
            app.ROI_ID_BG_CB.ValueChangedFcn = createCallbackFcn(app, @ROI_ID_BG_CBValueChanged, true);
            app.ROI_ID_BG_CB.Visible = 'off';
            app.ROI_ID_BG_CB.Text = 'ROI ID background';
            app.AddROIFreehandButton = uibutton(app.UIFigure, 'push');
            app.AddROIFreehandButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIFreehandButtonPushed, true);
            app.AddROIFreehandButton.Text = 'Add ROI (Freehand)';
            app.DeleteROIFreehandButton = uibutton(app.UIFigure, 'push');
            app.DeleteROIFreehandButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIFreehandButtonPushed, true);
            app.DeleteROIFreehandButton.Text = 'Delete ROI (Freehand)';
            app.DeleteROILineButton = uibutton(app.UIFigure, 'push');
            app.DeleteROILineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROILineButtonPushed, true);
            app.DeleteROILineButton.Text = 'Delete ROI (Line)';
            app.ROIs_Label = uilabel(app.UIFigure);
            app.ROIs_Label.Text = 'ROIs: 0';
            app.ROIsMinSize_Label = uilabel(app.UIFigure);
            app.ROIsMinSize_Label.Text = 'ROIs < minSize: ----';
            app.DeleteROIsminSizePB = uibutton(app.UIFigure, 'push');
            app.DeleteROIsminSizePB.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIsminSizePBPushed, true);
            app.DeleteROIsminSizePB.Text = 'Delete ROIs < minSize';
            app.AddStimulusSiteButton = uibutton(app.UIFigure, 'push');
            app.AddStimulusSiteButton.ButtonPushedFcn = createCallbackFcn(app, @AddStimulusSiteButtonPushed, true);
            app.AddStimulusSiteButton.Text = 'Add Stimulus Site';
            app.DeleteStimulusSiteButton = uibutton(app.UIFigure, 'push');
            app.DeleteStimulusSiteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStimulusSiteButtonPushed, true);
            app.DeleteStimulusSiteButton.Text = 'Delete Stimulus Site';
            app.SmoothingTextAreaLabel = uilabel(app.UIFigure);
            app.SmoothingTextAreaLabel.Text = 'Smoothing';
            app.SmoothingTA = uitextarea(app.UIFigure);
            app.SmoothingTA.Value = {'4'};
            app.backgroundPB = uibutton(app.UIFigure, 'push');
            app.backgroundPB.ButtonPushedFcn = createCallbackFcn(app, @backgroundPBPushed, true);
            app.backgroundPB.Text = 'Define Background';
            app.BackgroundValueEditFieldLabel = uilabel(app.UIFigure);
            app.BackgroundValueEditFieldLabel.Text = 'Background Value';
            app.backgroundEF = uieditfield(app.UIFigure, 'numeric');
            app.FrameEF = uieditfield(app.UIFigure, 'numeric');
            app.FrameEF.Limits = [1 Inf];
            app.FrameEF.ValueChangedFcn = createCallbackFcn(app, @FrameEFValueChanged, true);
            app.FrameEF.Editable = 'off';
            app.FrameEF.Visible = 'off';
            app.FrameEF.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentationGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end