classdef StackGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StackGUIUIFigure               matlab.ui.Figure
        TimelapsefpsEditFieldLabel     matlab.ui.control.Label
        TimelapseFpsEF                 matlab.ui.control.NumericEditField
        AddBadFrameintoTableLabel      matlab.ui.control.Label
        AddBadFrameEF                  matlab.ui.control.NumericEditField
        cMinEditFieldLabel             matlab.ui.control.Label
        cMinEF                         matlab.ui.control.NumericEditField
        cMaxEditFieldLabel             matlab.ui.control.Label
        cMaxEF                         matlab.ui.control.NumericEditField
        FrameLabel                     matlab.ui.control.Label
        FrameEF                        matlab.ui.control.NumericEditField
        FrameSlider                    matlab.ui.control.Slider
        CropPB                         matlab.ui.control.Button
        CropEditField                  matlab.ui.control.NumericEditField
        CropDropDown                   matlab.ui.control.DropDown
        minus1PB                       matlab.ui.control.Button
        plus1PB                        matlab.ui.control.Button
        BadFramesTable                 matlab.ui.control.Table
        IntensityTable                 matlab.ui.control.Table
        SubstituteBadFramesPB          matlab.ui.control.Button
        SubstitutedFramesLabel         matlab.ui.control.Label
        RemoveFirstOrLastFramesButton  matlab.ui.control.Button
        ResetBadFramesTablePB          matlab.ui.control.Button
        TimelapsePB                    matlab.ui.control.Button
        StackAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp     % app    - handle to mainApp
        stack       % array  - imported from mainApp
        stackInfo   % struct - imported from mainApp
        fileName    % string - imported from mainApp
        filePath    % string - imported from mainApp
        dataPath    % string - imported from mainApp
        
        % The basic numeric data type is double, so giving a single number
        % type int deliberately is not worth the effort
        
        % same info as in stackInfo, just easier acces and readability
        xPixels % double - number of pixels in x direction
        yPixels % double - number of pixels in y direction
        nFrames % double - numbers of frames (z-direction)
        fps     % double - Acquisition frame rate
        % frame is stored in the Frame edit field (FrameEF) so it is easier
        % to keep track compared to app.frame, app.FrameEditField.Value and
        % app.FrameSlider.Value
        
        cLimit  % double - Limit of the value for colorbar, should be between 0 and 65535
        % cMin and cMax are defined as the Value of the cMin/cMax edit field (EF)
        % They are loaded from stackInfo
        
        cropListener    % Listener - calls function when clicking on StackAxes
        cropArea        % array - indicates the to-be-cropped area
        
        frameIntensity  % 1 x nFrames vector used for the second column of BadFramesTable
        
        playTimelapse % indicates if timelapse is running
        
    end
    
    methods (Access = private)
        
        function switchInteractivity(app,status)
            for i = 1:length(app.StackGUIUIFigure.Children)
                % Not all Components (e.g. Axes) have the Enable property
                try
                    app.StackGUIUIFigure.Children(i).Enable = status;
                catch
                    % Everything is fine
                end
            end
            app.IntensityTable.Enable = 'off';
        end
        
        function plotStack(app)
            imagesc(app.stack(:,:,app.FrameEF.Value), 'Parent', app.StackAxes, ...
                [app.cMinEF.Value app.cMaxEF.Value]);
            % plot the crop area
            hold(app.StackAxes,'on')
            area(app.StackAxes, app.cropArea,[app.yPixels,app.yPixels], ...
                    'FaceColor','r','Facealpha',0.4)
            hold(app.StackAxes,'off')
            
            title(app.StackAxes, ...
                sprintf("%s \t \t Frame %.f \t at \t %.2f seconds", ...
                app.fileName, app.FrameEF.Value, (app.FrameEF.Value-1)*app.fps), ...
                'Interpreter','none');

            plotScaleBar(app)
        end
        
        function plotScaleBar(app)
            %scalebarsize in µm
            sbs = 50;
            fsize = 10;
            
            %scalebar pixels
            sbp = sbs/app.mainApp.stackInfo.xPixelSize;
            
            %If the width of the image is too small for a 50µm scalebar
            if sbp > 0.2*app.xPixels
                sbs = 20;
                sbp = sbs/app.mainApp.stackInfo.xPixelSize;
                fsize = 8;
            end
            
            % All the sbp and fsize subtraction is to ensure the scalebar
            % is in the lower right corner IN the plot
            line([app.xPixels - sbp - 0.5*fsize, app.xPixels - 0.5*fsize], ...
                 [app.yPixels - 1.5*fsize, app.yPixels - 1.5*fsize], ...
                'Parent', app.StackAxes, 'LineWidth', 0.4*fsize, 'color', 'white')
            scaleBarText = [num2str(sbs), ' µm'];
            text(app.StackAxes, app.xPixels - sbp, app.yPixels - 0.6*fsize, ...
                scaleBarText, 'fontsize',fsize, 'color','white');
        end
        
        
       function cLimitChange(app)
            % There needs to be a "range"
            if abs(app.cMinEF.Value - app.cMaxEF.Value) == 0
                % If lower value is zero - it can´t be decreased
                if app.cMinEF.Value == app.cLimit(1)
                    app.cMaxEF.Value = app.cMinEF.Value +1;
                else
                    app.cMinEF.Value = app.cMaxEF.Value -1;
                end
            end
            
            app.cMinEF.Limits = [app.cLimit(1), app.cMaxEF.Value];
            app.cMaxEF.Limits = [app.cMinEF.Value, app.cLimit(2)];
        end
        
        
        %Sets the cropBorder whenever the uer clicks on StackAxes
        function mouseClick(app)
            x = app.StackAxes.CurrentPoint(1);
            y = app.StackAxes.CurrentPoint(3);
            %pos = app.StackAxes.Position;
            % UIAxes has no own event Listener, so we have to "listen"
            % to UIFigure, but then still get the mouse pos in relation
            % to UIAxes (CurrentPoint of StackAxes)
            if and (x > 0, x < app.xPixels)
                if and (y > 0, y < app.yPixels)
                    app.CropEditField.Value = x;
                    cropChange(app)
                end
            end
        end
        
        function cropChange(app)
            if strcmp(app.CropDropDown.Value,'from')
                app.cropArea = [app.CropEditField.Value, app.xPixels];
            elseif strcmp(app.CropDropDown.Value, 'up to')
                app.cropArea = [1, app.CropEditField.Value];
            end
            
            % The crop option would do anything in this case anyway, so
            % better not give the user hopes
            if app.cropArea(1) == app.cropArea(2)
                app.CropPB.Enable = false;
            else
                app.CropPB.Enable = true;
            end
            plotStack(app)
        end
        

        function cropStack(app)
            cP = app.CropEditField.Value;
            % No cropping needed in those cases
            if or(cP == 0, cP == app.xPixels)
                return
            end
            
            wb = waitbar(0,'Please wait while cropping');
            
            if strcmp(app.CropDropDown.Value, "from")
                app.stack = app.stack(:,1:cP,:);
                if isfield(app.stackInfo,'cropped')
                    % See cropStack for a similar explanation - important
                    % for loading info from different, but similar file
                    app.stackInfo.cropped(2) = app.stackInfo.cropped(1)+cP-1;
                else
                    % We have to create the field
                    app.stackInfo.cropped = [1,cP];
                end
                
            elseif strcmp(app.CropDropDown.Value, "up to")
                app.stack = app.stack(:,cP:end,:);
                if isfield(app.stackInfo,'cropped')
                    app.stackInfo.cropped(1) = app.stackInfo.cropped(1)+cP-1;
                else
                    % We have to create the field
                    app.stackInfo.cropped = [cP,size(app.stack,2)];
                end
                
            else
                errordlg("Something unexpected happened in the function cropStack")
                return
            end
            
            waitbar(0.2,wb)
            
            app.stackInfo = SaveStack(app.dataPath, app.fileName, app.stack, app.stackInfo);
            
            waitbar(0.8,wb)
            updateMainApp(app)
            startupReload(app)
            close(wb)
        end
        
        function populateBadFramesTable(app)
            % Very close to the function in FluoroSNNAP 2.5
            % Initially only the maxium value of a frame was considered, which lead to
            % problems with too bright cells that were considered  artifacts, while
            % some stimulation artifacts were not registered.
            % Includes detection of partly empty frames as well
    
            % First we get the max value of each column of each frame
            % Column rather than row to not take beamflybacks into account too much
            colMax = squeeze(max(app.stack,[],1));
            % Then we compute the mean of all colMax per frame to get an
            % "intensity" value per frame
            frameColMaxMean = transpose(mean(colMax,1));
            % This framemedian will give a good metric of how bright a frame should be
            frameMaxMedian = median(frameColMaxMean);
            % This function is good for finding horizontal stimulation
            % artifacts, that were present in some cases, while irgnoring small bright cells.
            stimFrameIndex = find(frameColMaxMean > frameMaxMedian*1.5);
            if numel(stimFrameIndex) > app.nFrames/10
                stimFrameIndex = find(frameColMaxMean > frameMaxMedian*2);
                if numel(stimFrameIndex) > app.nFrames/10
                    stimFrameIndex = find(frameColMaxMean > frameMaxMedian*3);
                    % If too many frames are recognized as artefacts, then
                    % the overall activity level may be too high to
                    % recognize artefacts properly - so leave it empty
                    if numel(stimFrameIndex) > app.nFrames/10
                        stimFrameIndex = [];
                    end
                end
            end
    
            % Empty frames are rather easy, partly empty frames have some rows on
            % the bottom or top part with no signal, while the rest is fine
            rowMin = squeeze(min(app.stack,[],2));
            rowMean = squeeze(mean(app.stack,2));
            % An arbitrary number that worked fine during testing. we need to multiply
            % rowMin by something to not include most of the rather silent frames
            arbitraryNum = 1.05;
            % Finds all rows that fulfill the criterium and indicates the frame
            % there were on
            [~,minRowsInFrame] = find(rowMean < rowMin*arbitraryNum);
            counts = histcounts(minRowsInFrame,app.nFrames);
            %This arbitrary number indicates what fraction of the rows has to be
            %"empty" for the whole frame to be considered useless
            arbitraryNum = 0.10;
            emptyFrameIndex = find(counts > arbitraryNum*app.yPixels);
            
            % Tifs with a bad signal-to-noise ratio will be evaluated as mostly empty,
            % so we will need to adjust for that. The fraction is arbitrary again
            if numel(emptyFrameIndex) > app.nFrames/10
                arbitraryNum = 0.15;
                emptyFrameIndex = find(counts > arbitraryNum*app.yPixels);
                % If too many frames are recognized as empty, change the
                % sensitivity
                if numel(emptyFrameIndex) > app.nFrames/10
                    arbitraryNum = 0.25;
                    emptyFrameIndex = find(counts > arbitraryNum*app.yPixels);
                    % Still too many --> Most frames must be without action
                    if numel(emptyFrameIndex) > app.nFrames/10
                        emptyFrameIndex = [];
                    end
                end
            end
            
            % If all frames are fine, the array will be empty. Then we can´t
            % transpose it. This prevents the error: Transposing is needed
            % as stimFrameIndex is a colVec, emptyFrameIndex is a rowVec so far
            if ~isempty(emptyFrameIndex)
                emptyFrameIndex = transpose(emptyFrameIndex(1,:));
            end
            
            %Often artefact frames include empty rows as well
            badFrames = unique([emptyFrameIndex;stimFrameIndex]);
            % round to display whole numbers, *100 to show %frame intensity
            % compared to the MaxMedian
            app.frameIntensity = round(frameColMaxMean*100/frameMaxMedian);
            % Both tables are need individually, as the left/right arrow
            % key would not work otherwise, due to CellSelect being called
            % as well - basicly resetting the seleced frame. Sadly it seems
            % like the Interactivity/Enable can´t be disabled for a single
            % column
            if isempty(badFrames)
                app.BadFramesTable.Data = [0;0;0];
                app.IntensityTable.Data = [0;0;0];
            else
                app.BadFramesTable.Data = badFrames;
                app.IntensityTable.Data = app.frameIntensity(badFrames);
            end
            
        end
       
        
        function updateTables(app)
            % Frames have to be sorted for the substitution to work
            % properly (continous badFrames)
            app.BadFramesTable.Data = sort(app.BadFramesTable.Data);
            for i=1:numel(app.BadFramesTable.Data)
                if app.BadFramesTable.Data(i) == 0
                    app.IntensityTable.Data(i,1) = 0;
                else
                    app.IntensityTable.Data(i,1) = app.frameIntensity(app.BadFramesTable.Data(i));
                end
            end
            % If an entry of the badFramesTable was deleted, then one
            % Intensity value must be deleted as well (meaning no more
            % Intensity entries than badFrames entries
            app.IntensityTable.Data = app.IntensityTable.Data(1:i,1);
        end
        
                
        function subBadFrames(app)
            % Sum works similiar to "isempty", but skips only "0" aswell
            if sum(app.BadFramesTable.Data) == 0
                return
            end
            % The 0 are just placeholders to not let the table get empty
            badFrames = app.BadFramesTable.Data(app.BadFramesTable.Data > 0);
            % Frames have to be sorted for the substitution to work
            % properly (continous badFrames), this should be accounted for
            % when added new entries in badFramesTable, but just to be sure
            badFrames = sort(badFrames);
            [app.stack, app.stackInfo] = ...
                SubstituteBadFrames(app.stack, app.stackInfo, badFrames, app.dataPath, app.fileName);
            updateMainApp(app)
            startupReload(app)
        end
        
        
        function removeFrames(app)
            direction = questdlg('Remove first or last frames?', ...
                'FrameRemoval', 'First', 'Last', 'Last');

            if strcmp(direction, 'First')
                % Removing the first frames will only keep the temporal
                % relation, e.g. distance between 2 points = 1/Hz, but the
                % new first frame will have time = 0s
                question = 'Remove first n frames up until frame (including):';
            elseif strcmp(direction, 'Last')
                question = 'Remove last frames starting from frame:';
            else
                % happens if questdlg was closed
                return
            end
            
            fullQuestion = sprintf('%g frames in the stack \n %s',app.nFrames,question);
            
            waiting = true;
            wrongAnswer = sprintf('Prior input was wrong \n %s', fullQuestion);
            while waiting
                frames = inputdlg(fullQuestion,'FrameRemoval');
                % Prompt was closed
                if isempty(frames)
                    return
                % Checks if a value with a comma was entered which would
                % confuse the further program (str2double...)
                elseif contains(frames,{',','.'})
                    continue
                end
                %inputdlg return a cell-string
                frames = str2double(frames{:});
                % any non-numeric char is converted to  nan 
                if isnan(frames)
                    fullQuestion = wrongAnswer;
                elseif or(frames < 1, frames > app.nFrames)
                    fullQuestion = wrongAnswer;
                else
                    % So everything seems to be fine
                    break
                end
            end
            
            wb = waitbar(0,'Please wait while removing bad frames');
            if strcmp(direction, 'First')
                app.stack = app.stack(:,:,frames+1:end);
                if isfield(app.stackInfo,'remainingFrames')
                    % If the field is already existing the Stack already
                    % has a reduced amount of frames. Either the last
                    % frames where cut, so remainingFrames(1) is 1 or the
                    % first frames were already cut, so remainingFrames(1)
                    % is something defined before. This value has to be
                    % added again to ensure the overall information stays
                    % correct (cutting 10frames twice should yield 20
                    % instead of overwriting it to 10
                    app.stackInfo.remainingFrames(1) = frames+app.stackInfo.remainingFrames(1);
                else
                    app.stackInfo.remainingFrames = [frames,size(app.stack,3)];
                end
                
            elseif strcmp(direction, 'Last')
                app.stack = app.stack(:,:,1:frames-1);
                if isfield(app.stackInfo,'remainingFrames')
                    % The last frames will still be the "last" frames,
                    % though if we already cut the first 10frames, to last
                    % 20 frames of a stack of 200 will be 170:190 instead
                    % of 180:200, so we have to save 
                    app.stackInfo.remainingFrames(2) = app.stackInfo.remainingFrames(1) + frames-1;
                else
                    app.stackInfo.remainingFrames = [1,frames-1];
                end
                
            end
            
            waitbar(0.2,wb)
            app.stackInfo = SaveStack(app.dataPath, app.fileName, app.stack, app.stackInfo);
            
            waitbar(0.8,wb)
            updateMainApp(app)
            startupReload(app)
            close(wb)
        end
        
                
        function startupReload(app)
            app.nFrames = app.stackInfo.nFrames;
            app.yPixels = app.stackInfo.Height;
            app.xPixels = app.stackInfo.Width;
            
            % So the crop Area is always present, though not visible
            app.CropEditField.Value = app.xPixels;
            app.CropEditField.Limits = [1,app.xPixels];
            app.cropArea = [app.xPixels,app.xPixels];
            
            app.cMinEF.Value = app.stackInfo.cMin;
            app.cMaxEF.Value = app.stackInfo.cMax;
            
            % By setting XLim and YLim to the size of the axes, it is
            % ensured that 1 pixel of the image correspods to 1 pixel
            % of the axes --> No distortion
            % Though this will lead to problems if the image is bigger than
            % the limits....
            if or (app.StackAxes.Position(3) > app.xPixels, ...
                   app.StackAxes.Position(4) < app.yPixels)
                app.StackAxes.XLim = [0, app.StackAxes.Position(3)];
                app.StackAxes.YLim = [0, app.StackAxes.Position(4)];
            else
                errordlg('The image is bigger than the AxesWindow and is therefore distorted')
            end
            
            app.FrameEF.Limits = [1, app.nFrames];
            app.FrameSlider.Limits    = [1, app.nFrames];
            % Major ticks should start with 1, then show 10,20,.. and the
            % last frame, though if the last is a multiple of 10, it would
            % be displayed twice -> unique
            nTicks = 15;
            app.FrameSlider.MajorTicks = round(linspace(1,app.nFrames,nTicks));
            app.FrameSlider.Value = 1;
            FrameSliderValueChanged(app)
            
            populateBadFramesTable(app)
            app.AddBadFrameEF.Limits = [1, app.nFrames];
            app.AddBadFrameEF.Value = 1;
            
            if ~isempty(app.stackInfo.substitutedFrames)
                subFrames = app.stackInfo.substitutedFrames;
                subFrameFormat = repmat('%g, ', 1, numel(subFrames));
                app.SubstitutedFramesLabel.Text = ...
                    sprintf(['Substituted Frames: \n', subFrameFormat],subFrames);
                app.SubstitutedFramesLabel.FontSize = 10;
            end
        end
        
        
        function updateMainApp(app)
            try
                app.mainApp.updateFilesListBox(app.fileName,app.filePath)
            catch e
                errordlg("Could not reload the FilesListBox, click a file in the main window to make changes appear")
                disp(e)
            end
        end
        

    end
        
    
    methods (Access = public)
        
%         % This functions needs to be public to load "previous
%         % configurations" of similar files, e.g. UGpLG / URpLR
%         function substituteBadFrames(app,badFrames)
%             wb = waitbar(0,'Please wait while substituting bad frames');
%             % specifies how many continous frames are badFrames, 1 means a
%             % single one
%             contBF = 1;
%             % Specifies if frames should be substituted. As long there are
%             % continous ones, the functions goes further and just counts
%             % how many. Once the next one is not continous (diffBFT >1),
%             % substitution takes place
%             substitute = false;
%             
%             % so we know how far we already iterated through badFrames
%             i = 1;
%             while i <= length(badFrames)
%                 % For length = 1, "badFrames(i +/- 1)" won´t work
%                 if length(badFrames) > 1
%                     % true on last element of badFrames, but if the last n
%                     % badFrames are continous the extra if is needed
%                     if i == length(badFrames)
%                         substitute = true;
%                         if badFrames(i) == badFrames(i-1) + 1
%                             contBF = contBF+1;
%                         end
%                     elseif badFrames(i+1)-badFrames(i) == 1
%                         contBF = contBF +1;
%                     else
%                         substitute = true;
%                     end
%                 else
%                     substitute = true;
%                 end
%         
%                 if substitute == true
%                     substitute = false;
%                     % We want the difference of the last ok and the next ok frame,
%                     % if badFrames = [51,52,53] then badFrames(i) will be 53 when
%                     % this "if" is entered, contBF starts at 1 and is incremented
%                     % twice (at 52 and 53 as they are only 1 frame apart) so the
%                     % stack Dif is frame 53-3 = 50 up to 53+1 = 54 and 50 and 54 are
%                     % ok if 51,52,53 are bad - So we can calculate meaningful
%                     % "intermediate frames"
%                     stackDiff = app.stack(:,:,badFrames(i) + 1) - ...
%                                 app.stack(:,:,badFrames(i) - contBF);
%                     for f = 1:contBF
%                         app.stack(:,:,badFrames(i) - contBF + f) = ...
%                             app.stack(:,:,badFrames(i) - contBF) + stackDiff*f/contBF;
%                     end
%                     contBF = 1;
%                 end
%             i = i + 1;
%             end
%             app.stackInfo.substitutedFrames = unique([app.stackInfo.substitutedFrames;badFrames]);
%             waitbar(0.2,wb)
%             % Saves the stack, which edits the stackInfo, but outputs
%             % the new stackInfo aswell, so we don´t have to load it again
%             app.stackInfo = SaveStack(app.dataPath, app.fileName, app.stack, app.stackInfo);
%             waitbar(1,wb)
%             close(wb)
%         end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            % So no user interaction while loading
            switchInteractivity(app,'off')
            
            app.mainApp = mainApp;
            app.stack = mainApp.stack;
            app.stackInfo = mainApp.stackInfo;
            app.fileName = mainApp.fileName;
            app.filePath = mainApp.filePath;
            app.dataPath = mainApp.dataPath;
            app.fps     = mainApp.stackInfo.fps;
           
            % 65535 is the maximum value an uint16, like in the tif, can have
            app.cLimit = [0, 65535];
            app.cMinEF.Value = app.stackInfo.cMin;
            app.cMaxEF.Value = app.stackInfo.cMax;
            cLimitChange(app)
            
            disableDefaultInteractivity(app.StackAxes)
            app.StackAxes.Toolbar.Visible = false;
            
            % This is basicly the startup part, that has to be redone after
            % cropping, so it became its own function to be reusable
            startupReload(app)
            
            switchInteractivity(app,'on')
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.FrameSlider.Value = round(app.FrameSlider.Value);
            app.FrameEF.Value = app.FrameSlider.Value;
            % This Enable on/off prevents the sliding to stay selcted,
            % which would prevent the left/right arrowFunc
            % Somehow this crude method is the only/best option
            app.FrameSlider.Enable = false;
            drawnow()
            app.FrameSlider.Enable = true;
            
            plotStack(app)
        end

        % Value changed function: FrameEF
        function FrameEFValueChanged(app, event)
            app.FrameSlider.Value = app.FrameEF.Value;
            % This Enable on/off prevents the edit filed to stay selcted,
            % which would prevent the left/right arrowFunc
            % Somehow this crude method is tshe only/best option
            plotStack(app)
        end

        % Key press function: StackGUIUIFigure
        function StackGUIUIFigureKeyPress(app, event)
            key = event.Key;
            if and (strcmp(key,'leftarrow'), app.FrameEF.Value > 1)
            	app.FrameEF.Value = app.FrameEF.Value - 1;
            elseif and (strcmp(key,'rightarrow'), app.FrameEF.Value < app.nFrames)
                app.FrameEF.Value = app.FrameEF.Value + 1;
            else % No need to call FramesSlider if no arrow key was pressed
                return
            end
            FrameEFValueChanged(app)
        end

        % Value changed function: cMinEF
        function cMinEFValueChanged(app, event)
            cLimitChange(app)
            plotStack(app);
        end

        % Value changed function: cMaxEF
        function cMaxEFValueChanged(app, event)
            cLimitChange(app)
            plotStack(app);
        end

        % Value changed function: CropEditField
        function CropEditFieldValueChanged(app, event)
            cropChange(app)
        end

        % Value changed function: CropDropDown
        function CropDropDownValueChanged(app, event)
            cropChange(app)
        end

        % Button pushed function: plus1PB
        function plus1PBButtonPushed(app, event)
            if ~(app.CropEditField.Value >= app.xPixels)
                app.CropEditField.Value = app.CropEditField.Value + 1;
                cropChange(app);
            end
        end

        % Button pushed function: minus1PB
        function minus1PBButtonPushed(app, event)
            if ~(app.CropEditField.Value <= 1)
                app.CropEditField.Value = app.CropEditField.Value - 1;
                cropChange(app);   
            end
        end

        % Window button down function: StackGUIUIFigure
        function StackGUIUIFigureWindowButtonDown(app, event)
            mouseClick(app);
        end

        % Button pushed function: CropPB
        function CropPBButtonPushed(app, event)
            switchInteractivity(app,'off')
            cropStack(app)
            switchInteractivity(app,'on')
        end

        % Cell selection callback: BadFramesTable
        function BadFramesTableCellSelection(app, event)
            selectedIdx = event.Indices(1);
            selectedFrame = app.BadFramesTable.Data(selectedIdx);
            % Selecting the table heading selects all rows and therefore
            % returns more than one frame index, which can´t be displayed
            if and(length(selectedIdx) == 1, selectedFrame > 0)
                % This leads to interesting behavior, but it´s the best I
                % can think of right now, the other option woul dbe to
                % change the left/right arrowKeyFunc
                scroll(app.IntensityTable,'Row',selectedIdx)
                scroll(app.BadFramesTable,'Row',selectedIdx)
                app.FrameEF.Value = selectedFrame;
                FrameEFValueChanged(app)
            end
        end

        % Cell edit callback: BadFramesTable
        function BadFramesTableCellEdit(app, event)
            % Get the newly entered value
            newFrame = event.NewData;
            newIdx = event.Indices(1);
            % If the new frame occurs more than once, that means it was
            % present before, so we don´t need to add it "again"
            if or(isnan(newFrame),numel(find(app.BadFramesTable.Data==newFrame)) > 1)
                % 2 or fewer elements are a problem for the event listener
                % / java associated Matlab files for a reason beyond me. 
                % So we make sure not to have less than 2 elements
                if numel(app.BadFramesTable.Data) <= 3
                    app.BadFramesTable.Data(newIdx) = 0;
                else
                    app.BadFramesTable.Data(newIdx) = [];
                end
            else
                % There is no precomputed app.frameIntensity for frame 0 as
                % it does not exist and is just an empty placeholder
                if ~(newFrame == 0)
                % Frame 0 can´t be displayed, so FrameEFChange only occurs here
                    app.FrameEF.Value = newFrame;
                    FrameEFValueChanged(app)
                end
            end
            updateTables(app)
        end

        % Key press function: BadFramesTable
        function BadFramesTableKeyPress(app, event)
            % So even if in the BadFramesTable - Left/Right arrow will move
            % the frame to have an easier look around
            StackGUIUIFigureKeyPress(app,event)
        end

        % Value changed function: AddBadFrameEF
        function AddBadFrameEFValueChanged(app, event)
            newFrame = app.AddBadFrameEF.Value;
            if numel(find(app.BadFramesTable.Data == newFrame)) > 0
                % The frame is already present so nothing should be done
            else 
                app.BadFramesTable.Data = [app.BadFramesTable.Data; newFrame];
                updateTables(app)
            end
        end

        % Button pushed function: SubstituteBadFramesPB
        function SubstituteBadFramesPBButtonPushed(app, event)
            switchInteractivity(app,'off')
            subBadFrames(app)
            switchInteractivity(app,'on')
        end

        % Button pushed function: RemoveFirstOrLastFramesButton
        function RemoveFirstOrLastFramesButtonPushed(app, event)
            switchInteractivity(app,'off')
            removeFrames(app)
            switchInteractivity(app,'on')
        end

        % Button pushed function: ResetBadFramesTablePB
        function ResetBadFramesTablePBButtonPushed(app, event)
            app.BadFramesTable.Data = [0;0;0];
            app.IntensityTable.Data = [0;0;0];
        end

        % Button pushed function: TimelapsePB
        function TimelapsePBButtonPushed(app, event)
            if app.playTimelapse == 1
                app.playTimelapse = 0;
                app.TimelapsePB.Text = 'Start Timelapse';
            else
                app.playTimelapse = 1;
                switchInteractivity(app,'off')
                app.TimelapsePB.Enable = true;
                app.FrameSlider.Enable = true;
                app.TimelapsePB.Text = 'Stop Timelapse';
            end
            while app.playTimelapse
                tic
                if app.FrameEF.Value < app.nFrames
                    app.FrameEF.Value = app.FrameEF.Value + 1;
                    FrameEFValueChanged(app)
                    drawnow()
                    computingTime = toc;
                    % If the computation is not fast enough to meet the
                    % desired fps - the fps will automatically drop to a
                    % more feasible value
                    if computingTime > 1/app.TimelapseFpsEF.Value
                        app.TimelapseFpsEF.Value = floor(1/computingTime);
                    % Wait for the appropriate time to stay in the fps
                    % specified speed
                    elseif computingTime < 1/app.TimelapseFpsEF.Value
                        pause((1/app.TimelapseFpsEF.Value) - computingTime)
                    end
                else
                    app.playTimelapse = 0;
                end
            end
            app.TimelapsePB.Text = 'Start Timelapse';
            switchInteractivity(app,'on')
        end

        % Close request function: StackGUIUIFigure
        function StackGUIUIFigureCloseRequest(app, event)
            % If the Stack was changed during the StackGUI the changes
            % should be automatically applied in the mainApp without the
            % need of manual update by cicking listbox again
            try
                updateMainApp(app)
            catch e
                disp(e)
                error('Something went wrong when trying to close StackGUI, the mainWindow was most likely not properly updated!')
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StackGUIUIFigure = uifigure('Visible', 'off');
            app.StackGUIUIFigure.Name = 'StackGUI';
            app.StackGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @StackGUIUIFigureCloseRequest, true);
            app.StackGUIUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @StackGUIUIFigureWindowButtonDown, true);
            app.StackGUIUIFigure.KeyPressFcn = createCallbackFcn(app, @StackGUIUIFigureKeyPress, true);
            app.StackAxes = uiaxes(app.StackGUIUIFigure);
            title(app.StackAxes, 'fileName and frame info')
            app.StackAxes.XTick = [];
            app.StackAxes.YTick = [];
            app.TimelapsePB = uibutton(app.StackGUIUIFigure, 'push');
            app.TimelapsePB.ButtonPushedFcn = createCallbackFcn(app, @TimelapsePBButtonPushed, true);
            app.TimelapsePB.Text = 'Start Timelapse';
            app.ResetBadFramesTablePB = uibutton(app.StackGUIUIFigure, 'push');
            app.ResetBadFramesTablePB.ButtonPushedFcn = createCallbackFcn(app, @ResetBadFramesTablePBButtonPushed, true);
            app.ResetBadFramesTablePB.Text = 'Reset Table';
            app.RemoveFirstOrLastFramesButton = uibutton(app.StackGUIUIFigure, 'push');
            app.RemoveFirstOrLastFramesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFirstOrLastFramesButtonPushed, true);
            app.RemoveFirstOrLastFramesButton.Text = 'Remove First Or Last Frames';
            app.SubstitutedFramesLabel = uilabel(app.StackGUIUIFigure);
            app.SubstitutedFramesLabel.Text = 'Substituted Frames:';
            app.SubstituteBadFramesPB = uibutton(app.StackGUIUIFigure, 'push');
            app.SubstituteBadFramesPB.ButtonPushedFcn = createCallbackFcn(app, @SubstituteBadFramesPBButtonPushed, true);
            app.SubstituteBadFramesPB.Text = 'Substitute Bad Frames';
            app.IntensityTable = uitable(app.StackGUIUIFigure);
            app.IntensityTable.ColumnName = {'   Intensity'};
            app.IntensityTable.RowName = {};
            app.IntensityTable.Enable = 'off';
            app.BadFramesTable = uitable(app.StackGUIUIFigure);
            app.BadFramesTable.ColumnName = {'Bad frames'};
            app.BadFramesTable.RowName = {};
            app.BadFramesTable.ColumnEditable = [true false];
            app.BadFramesTable.CellEditCallback = createCallbackFcn(app, @BadFramesTableCellEdit, true);
            app.BadFramesTable.CellSelectionCallback = createCallbackFcn(app, @BadFramesTableCellSelection, true);
            app.BadFramesTable.KeyPressFcn = createCallbackFcn(app, @BadFramesTableKeyPress, true);
            app.plus1PB = uibutton(app.StackGUIUIFigure, 'push');
            app.plus1PB.ButtonPushedFcn = createCallbackFcn(app, @plus1PBButtonPushed, true);
            app.plus1PB.Text = '+1';
            app.minus1PB = uibutton(app.StackGUIUIFigure, 'push');
            app.minus1PB.ButtonPushedFcn = createCallbackFcn(app, @minus1PBButtonPushed, true);
            app.minus1PB.Text = '-1';
            app.CropDropDown = uidropdown(app.StackGUIUIFigure);
            app.CropDropDown.Items = {'from', 'up to'};
            app.CropDropDown.ValueChangedFcn = createCallbackFcn(app, @CropDropDownValueChanged, true);
            app.CropDropDown.Value = 'from';
            app.CropEditField = uieditfield(app.StackGUIUIFigure, 'numeric');
            app.CropEditField.RoundFractionalValues = 'on';
            app.CropEditField.ValueChangedFcn = createCallbackFcn(app, @CropEditFieldValueChanged, true);
            app.CropPB = uibutton(app.StackGUIUIFigure, 'push');
            app.CropPB.ButtonPushedFcn = createCallbackFcn(app, @CropPBButtonPushed, true);
            app.CropPB.Enable = 'off';
            app.CropPB.Text = 'Crop';
            app.FrameSlider = uislider(app.StackGUIUIFigure);
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameEF = uieditfield(app.StackGUIUIFigure, 'numeric');
            app.FrameEF.Limits = [0 1];
            app.FrameEF.ValueChangedFcn = createCallbackFcn(app, @FrameEFValueChanged, true);
            app.FrameEF.Value = 1;
            app.FrameLabel = uilabel(app.StackGUIUIFigure);
            app.FrameLabel.Text = 'Frame: ';
            app.cMaxEF = uieditfield(app.StackGUIUIFigure, 'numeric');
            app.cMaxEF.Limits = [0 Inf];
            app.cMaxEF.ValueDisplayFormat = '%11.5g';
            app.cMaxEF.ValueChangedFcn = createCallbackFcn(app, @cMaxEFValueChanged, true);
            app.cMaxEF.Value = 500;
            app.cMaxEditFieldLabel = uilabel(app.StackGUIUIFigure);
            app.cMaxEditFieldLabel.Text = 'cMax';
            app.cMinEF = uieditfield(app.StackGUIUIFigure, 'numeric');
            app.cMinEF.Limits = [0 Inf];
            app.cMinEF.ValueDisplayFormat = '%11.5g';
            app.cMinEF.ValueChangedFcn = createCallbackFcn(app, @cMinEFValueChanged, true);
            app.cMinEF.Value = 100;
            app.cMinEditFieldLabel = uilabel(app.StackGUIUIFigure);
            app.cMinEditFieldLabel.Text = 'cMin';
            app.AddBadFrameEF = uieditfield(app.StackGUIUIFigure, 'numeric');
            app.AddBadFrameEF.Limits = [1 Inf];
            app.AddBadFrameEF.ValueChangedFcn = createCallbackFcn(app, @AddBadFrameEFValueChanged, true);
            app.AddBadFrameEF.Value = 1;
            app.AddBadFrameintoTableLabel = uilabel(app.StackGUIUIFigure);
            app.AddBadFrameintoTableLabel.Text = 'Add Bad Frame into Table ';
            app.TimelapseFpsEF = uieditfield(app.StackGUIUIFigure, 'numeric');
            app.TimelapseFpsEF.Limits = [1 Inf];
            app.TimelapseFpsEF.Value = 1;
            app.TimelapsefpsEditFieldLabel = uilabel(app.StackGUIUIFigure);
            app.TimelapsefpsEditFieldLabel.Text = 'Timelapse fps';
            % Show the figure after all components are created
            app.StackGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StackGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StackGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StackGUIUIFigure)
        end
    end
end